classdef Data_Acquisition_NIDAQ_2018b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VoltageRecordingV1UIFigure  matlab.ui.Figure
        DataDirectoryButton         matlab.ui.control.Button
        EditField                   matlab.ui.control.EditField
        DevicesListBoxLabel         matlab.ui.control.Label
        DevicesListBox              matlab.ui.control.ListBox
        AnalogInputListBox_3Label   matlab.ui.control.Label
        AnalogInputListBox          matlab.ui.control.ListBox
        DigitalInputListBoxLabel    matlab.ui.control.Label
        DigitalInputListBox         matlab.ui.control.ListBox
        UIAxes                      matlab.ui.control.UIAxes
        SelectChannelsButton        matlab.ui.control.StateButton
        SampleRateEditFieldLabel    matlab.ui.control.Label
        SampleRateEditField         matlab.ui.control.NumericEditField
        BitEditFieldLabel           matlab.ui.control.Label
        BitEditField                matlab.ui.control.NumericEditField
        HzLabel                     matlab.ui.control.Label
        SessionEditFieldLabel       matlab.ui.control.Label
        SessionEditField            matlab.ui.control.NumericEditField
        StartRecordingButton        matlab.ui.control.StateButton
        Lamp                        matlab.ui.control.Lamp
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        XRangeSpinnerLabel          matlab.ui.control.Label
        XRangeSpinner               matlab.ui.control.Spinner
        WindowDisplaySwitchLabel    matlab.ui.control.Label
        WindowDisplaySwitch         matlab.ui.control.ToggleSwitch
        YLowerSpinnerLabel          matlab.ui.control.Label
        YLowerSpinner               matlab.ui.control.Spinner
        YUpperSpinnerLabel          matlab.ui.control.Label
        YUpperSpinner               matlab.ui.control.Spinner
        VLabel_2                    matlab.ui.control.Label
        VLabel_3                    matlab.ui.control.Label
        sLabel                      matlab.ui.control.Label
        UpdateIntervalSpinnerLabel  matlab.ui.control.Label
        UpdateIntervalSpinner       matlab.ui.control.Spinner
        sLabel_2                    matlab.ui.control.Label
        VoltageLimtDropDownLabel    matlab.ui.control.Label
        VoltageLimtDropDown         matlab.ui.control.DropDown
        AnalogInputListBox_2Label   matlab.ui.control.Label
        AnalogInputListBox_2        matlab.ui.control.ListBox
        SingleEndedLabel            matlab.ui.control.Label
        DifferentialLabel           matlab.ui.control.Label
        VoltageLimtDropDown_2Label  matlab.ui.control.Label
        VoltageLimtDropDown_2       matlab.ui.control.DropDown
        LegendCheckBox              matlab.ui.control.CheckBox
        PeekSignalButton            matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        devices
        SelectChannelsButton_Press
        device
        AI_Subsystem
        DIO_Subsystem
        SRmax
        LogDir
        VoltageMax
        VoltageMin
        XRange_Max
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.devices = daq.getDevices();
            if isempty(app.devices)
                errordlg('No DAQ device detected', 'Error');
                delete(app.VoltageRecordingV1UIFigure);
                return;
            end
            for i = 1:numel(app.devices)
                app.DevicesListBox.Items{i} = app.devices(i).Model;
            end
            app.SelectChannelsButton_Press = 0;
            app.XRange_Max = [];
        end

        % Value changed function: DevicesListBox
        function DevicesListBoxValueChanged(app, event)
            app.SelectChannelsButton_Press = 0;
            value = app.DevicesListBox.Value;
            app.SampleRateEditField.Value = Inf;
            app.VoltageLimtDropDown.Items = cell(0);
            app.VoltageLimtDropDown.ItemsData = [];
            app.VoltageLimtDropDown_2.Items = cell(0);
            app.VoltageLimtDropDown_2.ItemsData = [];
            app.BitEditField.Value = Inf;
            if isempty(value)
                app.AnalogInputListBox.Items = cell(0);
                app.AnalogInputListBox_2.Items = cell(0);
                app.DigitalInputListBox.Items = cell(0);
                return;
            end
            for i = 1:numel(app.devices)
                if strcmp(app.devices(i).Model, value)
                    app.device = app.devices(i);
                end
            end
            Subsystems = app.device.Subsystems;
            app.AI_Subsystem = [];
            for i = 1:numel(Subsystems)
                switch Subsystems(i).SubsystemType
                    case 'AnalogInput'
                        app.AI_Subsystem = Subsystems(i);
                        app.AnalogInputListBox.Items = app.AI_Subsystem.ChannelNames;
                        app.AnalogInputListBox.ItemsData = 1:numel(app.AnalogInputListBox.Items);
                        app.AnalogInputListBox.Value = [];
                        app.AnalogInputListBox_2.Items = app.AI_Subsystem.ChannelNames(1:floor(numel(app.AI_Subsystem.ChannelNames)/2));
                        app.AnalogInputListBox_2.ItemsData = 1:numel(app.AnalogInputListBox_2.Items);
                        app.AnalogInputListBox_2.Value = [];
                    case 'DigitalIO'
                        app.DIO_Subsystem = Subsystems(i);
                        app.DigitalInputListBox.Items = app.DIO_Subsystem.ChannelNames;
                        app.DigitalInputListBox.ItemsData = 1:numel(app.DigitalInputListBox.Items);
                        app.DigitalInputListBox.Value = [];
                end
            end
            if ~isempty(app.AI_Subsystem)
                AnalogInputListBoxValueChanged(app, event);
            end
        end

        % Value changed function: SelectChannelsButton
        function SelectChannelsButtonValueChanged(app, event)
            value = app.SelectChannelsButton.Value;
            if value
                AI_ChannelNames = [app.AnalogInputListBox.Value app.AnalogInputListBox_2.Value];
                DIO_ChannelNames = app.DigitalInputListBox.Value;
                if isempty(AI_ChannelNames) && isempty(DIO_ChannelNames)
                    app.SelectChannelsButton.Value = 0;
                    return;
                end
                app.SampleRateEditField.Editable = 'On';
                if ~isempty(app.AnalogInputListBox.Value)
                    app.VoltageLimtDropDown.Enable = 'On';
                end
                if ~isempty(app.AnalogInputListBox_2.Value)
                    app.VoltageLimtDropDown_2.Enable = 'On';
                end
                app.YLowerSpinner.Enable = 'On';
                app.YUpperSpinner.Enable = 'On';
                app.DevicesListBox.Enable = 'Off';
                app.AnalogInputListBox.Enable = 'Off';
                app.AnalogInputListBox_2.Enable = 'Off';
                app.DigitalInputListBox.Enable = 'Off';
                app.SelectChannelsButton.Text = 'Reselect Channels';
            else
                app.SampleRateEditField.Editable = 'Off';
                app.VoltageLimtDropDown.Enable = 'Off';
                app.VoltageLimtDropDown_2.Enable = 'Off';
                app.YLowerSpinner.Enable = 'Off';
                app.YUpperSpinner.Enable = 'Off';
                app.AnalogInputListBox.Enable = 'On';
                app.AnalogInputListBox_2.Enable = 'On';
                app.DigitalInputListBox.Enable = 'On';
                app.DevicesListBox.Enable = 'On';
                app.SelectChannelsButton.Text = 'Select Channels';
            end
            app.SelectChannelsButton_Press = app.SelectChannelsButton_Press + 1;
        end

        % Value changed function: AnalogInputListBox
        function AnalogInputListBoxValueChanged(app, event)
            if isempty(app.AnalogInputListBox.Value)
                app.AnalogInputListBox.Value = [];
            else
                app.AnalogInputListBox.Value = sort(app.AnalogInputListBox.Value, 'ascend');
            end
            AI_ChannelNames = [app.AnalogInputListBox.Value app.AnalogInputListBox_2.Value];
            app.BitEditField.Value = app.AI_Subsystem.Resolution;
            app.SRmax = max(app.AI_Subsystem.RateLimit);
            if ~app.SelectChannelsButton_Press
                if isempty(AI_ChannelNames)
                    app.SampleRateEditField.Value = app.SRmax;
                else
                    app.SampleRateEditField.Value = app.SRmax/numel(AI_ChannelNames);
                end
                for i = 1:numel(app.AI_Subsystem.RangesAvailable)
                    app.VoltageLimtDropDown.Items{i} = [num2str(app.AI_Subsystem.RangesAvailable(i).Min)...
                        ' to ' num2str(app.AI_Subsystem.RangesAvailable(i).Max) ' '...
                        app.AI_Subsystem.RangesAvailable(i).Units];
                    app.VoltageLimtDropDown.ItemsData(i) = i;
                    app.VoltageLimtDropDown_2.Items{i} = [num2str(app.AI_Subsystem.RangesAvailable(i).Min)...
                        ' to ' num2str(app.AI_Subsystem.RangesAvailable(i).Max) ' '...
                        app.AI_Subsystem.RangesAvailable(i).Units];
                    app.VoltageLimtDropDown_2.ItemsData(i) = i;
                end
                app.VoltageMax = app.AI_Subsystem.RangesAvailable(1).Max;
                app.VoltageMin = app.AI_Subsystem.RangesAvailable(1).Min;
                app.YLowerSpinner.Value = app.VoltageMin;
                app.YUpperSpinner.Value = app.VoltageMax;
                YLowerSpinnerValueChanged(app, event);
                YUpperSpinnerValueChanged(app, event);
            else
                if isempty(AI_ChannelNames) && app.SampleRateEditField.Value > app.SRmax
                    app.SampleRateEditField.Value = app.SRmax;
                elseif app.SampleRateEditField.Value > app.SRmax/numel(AI_ChannelNames)
                    app.SampleRateEditField.Value = app.SRmax/numel(AI_ChannelNames);
                end
            end
            app.SampleRateEditField.Value = floor(app.SampleRateEditField.Value);
        end

        % Value changed function: SampleRateEditField
        function SampleRateEditFieldValueChanged(app, event)
            value = app.SampleRateEditField.Value;
            AI_ChannelNames = [app.AnalogInputListBox.Value app.AnalogInputListBox_2.Value];
            if value > app.SRmax/numel(AI_ChannelNames)
                value = app.SRmax/numel(AI_ChannelNames);
            elseif value <= 0
                value = 100;
            end
            app.SampleRateEditField.Value = floor(value);
        end

        % Button pushed function: DataDirectoryButton
        function DataDirectoryButtonPushed(app, event)
            try
                app.LogDir = uigetdir(app.LogDir, 'Choose a folder to save data');
            catch
                app.LogDir = uigetdir('C:\', 'Choose a folder to save data');
            end
            if app.LogDir ~= 0
                app.SessionEditField.Value = 1;
                app.EditField.Value = app.LogDir;
                appdir = pwd;
                cd(app.LogDir);
                files = dir;
                for i = 3:numel(files)
                    filename = files(i).name;
                    if numel(filename) == 14
                        if strcmp(filename(1:7), 'Session') && strcmp(filename(end-3:end), '.bin')
                            app.SessionEditField.Value = max(app.SessionEditField.Value, str2double(filename(8:10))) + 1;
                        end
                    end
                end
                cd(appdir);
            else
                app.EditField.Value = '';
            end
            figure(app.VoltageRecordingV1UIFigure);
        end

        % Value changed function: StartRecordingButton
        function StartRecordingButtonValueChanged(app, event)
            persistent lh RecSession fid x y
            if ~app.SelectChannelsButton.Value
                msgbox('No channel selected.', 'HELP', 'help');
                app.StartRecordingButton.Value = 0;
                return;
            end
            value = app.StartRecordingButton.Value;
            if value
                app.PeekSignalButton.Enable = 'Off';
            else
                app.PeekSignalButton.Enable = 'On';
            end
            if app.StartRecordingButton.Value || app.PeekSignalButton.Value
                RecSession = daq.createSession(app.device.Vendor.ID);
                RecSession.IsContinuous = true;
                if ~isempty(app.DigitalInputListBox.Value)
                    dioChannels = addDigitalChannel(RecSession, app.device.ID, app.DigitalInputListBox.Items(app.DigitalInputListBox.Value), 'InputOnly');
                else
                    dioChannels = [];
                end
                if ~isempty(app.AnalogInputListBox.Value)
                    aiChannels_SingleEnded = addAnalogInputChannel(RecSession, app.device.ID,...
                        app.AnalogInputListBox.Items(app.AnalogInputListBox.Value), 'Voltage');
                else
                    aiChannels_SingleEnded = [];
                end
                if ~isempty(app.AnalogInputListBox_2.Value)
                    try
                        aiChannels_Differential = addAnalogInputChannel(RecSession, app.device.ID,...
                            app.AnalogInputListBox_2.Items(app.AnalogInputListBox_2.Value), 'Voltage');
                    catch
                        stop(RecSession);
                        delete(RecSession);
                        clear RecSession;
                        if app.StartRecordingButton.Value
                            app.StartRecordingButton.Value = 0;
                            app.StartRecordingButton.Text = 'Start Recording';
                            app.PeekSignalButton.Enable = 'On';
                        elseif app.PeekSignalButton.Value
                            app.PeekSignalButton.Value = 0;
                            app.StartRecordingButton.Enable = 'On';
                        end
                        errordlg('SingleEnded and Differential Channel Conflict.', 'Error');
                        return;
                    end
                else
                    aiChannels_Differential = [];
                end
                if isempty(app.AnalogInputListBox.Value) && isempty(app.AnalogInputListBox_2.Value)
                    addAnalogInputChannel(RecSession, app.device.ID, 0, 'Voltage');
                end
                nChannels = numel(dioChannels) + numel(aiChannels_SingleEnded) + numel(aiChannels_Differential);
                try
                    RecSession.Rate = app.SampleRateEditField.Value;
                catch
                    stop(RecSession);
                    delete(RecSession);
                    clear RecSession;
                    if app.StartRecordingButton.Value
                        app.StartRecordingButton.Value = 0;
                        app.StartRecordingButton.Text = 'Start Recording';
                    elseif app.PeekSignalButton.Value
                        app.PeekSignalButton.Value = 0;
                        app.StartRecordingButton.Enable = 'On';
                    end
                    errordlg('DAQ device doesn''t support clocked digital input sampling.', 'Error');
                    return;
                end
                if ~isempty(aiChannels_SingleEnded)
                    for i = 1:numel(aiChannels_SingleEnded)
                        aiChannels_SingleEnded(i).Range = app.AI_Subsystem.RangesAvailable(app.VoltageLimtDropDown.Value);
                        aiChannels_SingleEnded(i).TerminalConfig = 'SingleEnded';
                    end
                end
                if ~isempty(aiChannels_Differential)
                    for i = 1:numel(aiChannels_Differential)
                        aiChannels_Differential(i).Range = app.AI_Subsystem.RangesAvailable(app.VoltageLimtDropDown_2.Value);
                        aiChannels_Differential(i).TerminalConfig = 'Differential';
                    end
                end
                RecSession.NotifyWhenDataAvailableExceeds = round(RecSession.Rate*app.UpdateIntervalSpinner.Value);
                fid = [];
                if ~app.PeekSignalButton.Value
                    fid_txt = fopen([app.LogDir '\Session' num2str(app.SessionEditField.Value, '%03d') '.txt'], 'wt');
                    fprintf(fid_txt, 'Device: %s\n\n', app.DevicesListBox.Value);
                    if ~isempty(app.DigitalInputListBox.Value)
                        fprintf(fid_txt, 'Digital Input: %s\n', app.DigitalInputListBox.Items{app.DigitalInputListBox.Value});
                        fprintf(fid_txt, '\n');
                    else
                        fprintf(fid_txt, '%s\n\n', 'Digital Input: ');
                    end
                    if ~isempty(app.AnalogInputListBox.Value)
                        fprintf(fid_txt, 'Analog Input (SingleEnded): %s\n', app.AnalogInputListBox.Items{app.AnalogInputListBox.Value});
                        fprintf(fid_txt, 'Voltage Range: %s\n', app.VoltageLimtDropDown.Items{app.VoltageLimtDropDown.Value});
                        fprintf(fid_txt, 'Sample Rate: %u Hz\n\n', RecSession.Rate);
                    else
                        fprintf(fid_txt, '%s\n\n', 'Analog Input (SingleEnded): ');
                    end
                    if ~isempty(app.AnalogInputListBox_2.Value)
                        fprintf(fid_txt, 'Analog Input (Differential): %s\n', app.AnalogInputListBox_2.Items{app.AnalogInputListBox_2.Value});
                        fprintf(fid_txt, 'Voltage Range: %s\n', app.VoltageLimtDropDown.Items{app.VoltageLimtDropDown.Value});
                        fprintf(fid_txt, 'Sample Rate: %u Hz\n', RecSession.Rate);
                    else
                        fprintf(fid_txt, '%s\n', 'Analog Input (Differential): ');
                    end
                    fclose(fid_txt);
                    fid = fopen([app.LogDir '\Session' num2str(app.SessionEditField.Value, '%03d') '.bin'], 'w');
                end
                cla(app.UIAxes);
                cla(app.UIAxes_2);
                cla(app.UIAxes_3);
                cla(app.UIAxes_4);
                if strcmp(app.WindowDisplaySwitch.Value, 'On') || app.PeekSignalButton.Value
                    y = nan(round(app.XRangeSpinner.Value*RecSession.Rate), nChannels);
                    x = linspace(0, app.XRangeSpinner.Value, size(y, 1));
                    app.XRange_Max = app.XRangeSpinner.Value;
                    cmap = jet(256);
                    if nChannels <= 4
                        cmap = [0 0 0];
                    else
                        cmap = cmap(round(1:(size(cmap, 1)-1)/(ceil(nChannels/4)-1):size(cmap, 1)), :);
                    end
                    hlines = zeros(1, nChannels);
                    Channel_Names = [app.DigitalInputListBox.Items(app.DigitalInputListBox.Value) ...
                        app.AnalogInputListBox.Items(app.AnalogInputListBox.Value) app.AnalogInputListBox_2.Items(app.AnalogInputListBox_2.Value)];
                    for i = 1:numel(Channel_Names)
                        if i <= numel(app.DigitalInputListBox.Value)
                            Channel_Names{i} = ['DIO: ' Channel_Names{i}];
                        elseif i <= numel(app.DigitalInputListBox.Value) + numel(app.AnalogInputListBox.Value)
                            Channel_Names{i} = ['SingleEnded: ' Channel_Names{i}];
                        else
                            Channel_Names{i} = ['Differential: ' Channel_Names{i}];
                        end
                    end
                    labels_UIAxes = cell(0);
                    labels_UIAxes_2 = cell(0);
                    labels_UIAxes_3 = cell(0);
                    labels_UIAxes_4 = cell(0);
                    for i = 1:nChannels
                        switch mod(i, 4)
                            case 1
                                hlines(i) = plot(app.UIAxes, x, y(:, i), '-', 'Color', cmap(ceil(i/4), :));
                                labels_UIAxes(ceil(i/4)) = Channel_Names(i);
                            case 2
                                hlines(i) = plot(app.UIAxes_2, x, y(:, i), '-', 'Color', cmap(ceil(i/4), :));
                                labels_UIAxes_2(ceil(i/4)) = Channel_Names(i);
                            case 3
                                hlines(i) = plot(app.UIAxes_3, x, y(:, i), '-', 'Color', cmap(ceil(i/4), :));
                                labels_UIAxes_3(ceil(i/4)) = Channel_Names(i);
                            case 0
                                hlines(i) = plot(app.UIAxes_4, x, y(:, i), '-', 'Color', cmap(ceil(i/4), :));
                                labels_UIAxes_4(ceil(i/4)) = Channel_Names(i);
                        end
                    end
                    legend(app.UIAxes, labels_UIAxes, 'Location', 'northeast');
                    legend(app.UIAxes, 'boxoff');
                    if ~isempty(labels_UIAxes_2)
                        legend(app.UIAxes_2, labels_UIAxes_2, 'Location', 'northeast');
                        legend(app.UIAxes_2, 'boxoff');
                    end
                    if ~isempty(labels_UIAxes_3)
                        legend(app.UIAxes_3, labels_UIAxes_3, 'Location', 'northeast');
                        legend(app.UIAxes_3, 'boxoff');
                    end
                    if ~isempty(labels_UIAxes_4)
                        legend(app.UIAxes_4, labels_UIAxes_4, 'Location', 'northeast');
                        legend(app.UIAxes_4, 'boxoff');
                    end
                    if ~app.LegendCheckBox.Value
                        legend(app.UIAxes, 'off');
                        legend(app.UIAxes_2, 'off');
                        legend(app.UIAxes_3, 'off');
                        legend(app.UIAxes_4, 'off');
                    end
                    lh = addlistener(RecSession, 'DataAvailable', @(src, event)logdata(src, event, fid, hlines, nChannels));
                else
                    lh = addlistener(RecSession, 'DataAvailable', @(src, event)logdata_display_off(src, event, fid, nChannels));
                end
                if app.StartRecordingButton.Value
                    app.Lamp.Color = [0 1 0];
                    app.StartRecordingButton.Text = 'Stop Recording';
                end
                app.SelectChannelsButton.Enable = 'Off';
                app.DataDirectoryButton.Enable = 'Off';
                app.WindowDisplaySwitch.Enable = 'Off';
                app.LegendCheckBox.Enable = 'Off';
                app.UpdateIntervalSpinner.Enable = 'Off';
                app.SampleRateEditField.Editable = 'Off';
                if ~isempty(app.AnalogInputListBox.Value)
                    app.VoltageLimtDropDown.Enable = 'Off';
                end
                if ~isempty(app.AnalogInputListBox_2.Value)
                    app.VoltageLimtDropDown_2.Enable = 'Off';
                end
                startBackground(RecSession);
            else
                try
                    stop(RecSession);
                    delete(lh);
                    delete(RecSession);
                catch
                end
                if ~isempty(fid)
                    fclose(fid);
                    app.Lamp.Color = [0.8 0.8 0.8];
                    app.StartRecordingButton.Text = 'Start Recording';
                    app.SessionEditField.Value = app.SessionEditField.Value + 1;
                end
                clear lh RecSession fid;
                app.SelectChannelsButton.Enable = 'On';
                app.DataDirectoryButton.Enable = 'On';
                app.WindowDisplaySwitch.Enable = 'On';
                app.LegendCheckBox.Enable = 'On';
                app.UpdateIntervalSpinner.Enable = 'On';
                app.SampleRateEditField.Editable = 'On';
                if ~isempty(app.AnalogInputListBox.Value)
                    app.VoltageLimtDropDown.Enable = 'On';
                end
                if ~isempty(app.AnalogInputListBox_2.Value)
                    app.VoltageLimtDropDown_2.Enable = 'On';
                end
                app.XRange_Max = [];
            end
            
            function logdata(src, event, fid, hlines, nChannels)
                tic
                if ~isempty(fid)
                    data = [event.TimeStamps event.Data(:, 1:nChannels)]';
                    fwrite(fid, data, 'double');
                end
                
                y(1:src.NotifyWhenDataAvailableExceeds, :) = [];
                y = [y; event.Data(:, 1:nChannels)];
                for n = 1:nChannels
                    set(hlines(n), 'YData', y(:, n));
                end
                toc
            end
            
            function logdata_display_off(~, event, fid, nChannels)
                tic
                data = [event.TimeStamps event.Data(:, 1:nChannels)]';
                fwrite(fid, data, 'double');
                toc
            end
        end

        % Value changed function: YLowerSpinner
        function YLowerSpinnerValueChanged(app, event)
            value = app.YLowerSpinner.Value;
            if value >= app.YUpperSpinner.Value || value < app.VoltageMin
                app.YLowerSpinner.Value = app.VoltageMin;
            end
            app.UIAxes.YLim = [app.YLowerSpinner.Value app.YUpperSpinner.Value];
            app.UIAxes_2.YLim = [app.YLowerSpinner.Value app.YUpperSpinner.Value];
            app.UIAxes_3.YLim = [app.YLowerSpinner.Value app.YUpperSpinner.Value];
            app.UIAxes_4.YLim = [app.YLowerSpinner.Value app.YUpperSpinner.Value];
        end

        % Value changed function: YUpperSpinner
        function YUpperSpinnerValueChanged(app, event)
            value = app.YUpperSpinner.Value;
            if value <= app.YLowerSpinner.Value || value > app.VoltageMax
                app.YUpperSpinner.Value = app.VoltageMax;
            end
            app.UIAxes.YLim = [app.YLowerSpinner.Value app.YUpperSpinner.Value];
            app.UIAxes_2.YLim = [app.YLowerSpinner.Value app.YUpperSpinner.Value];
            app.UIAxes_3.YLim = [app.YLowerSpinner.Value app.YUpperSpinner.Value];
            app.UIAxes_4.YLim = [app.YLowerSpinner.Value app.YUpperSpinner.Value];
        end

        % Value changed function: XRangeSpinner
        function XRangeSpinnerValueChanged(app, event)
            value = app.XRangeSpinner.Value;
            if ~isempty(app.XRange_Max)
                if value > app.XRange_Max
                    app.XRangeSpinner.Value = app.XRange_Max;
                    return;
                end
            end
            if value < app.UpdateIntervalSpinner.Value
                app.XRangeSpinner.Value = app.UpdateIntervalSpinner.Value;
            end
            app.UIAxes.XLim(2) = app.XRangeSpinner.Value;
            app.UIAxes_2.XLim(2) = app.XRangeSpinner.Value;
            app.UIAxes_3.XLim(2) = app.XRangeSpinner.Value;
            app.UIAxes_4.XLim(2) = app.XRangeSpinner.Value;
        end

        % Value changed function: UpdateIntervalSpinner
        function UpdateIntervalSpinnerValueChanged(app, event)
            value = app.UpdateIntervalSpinner.Value;
            if value < 0
                app.UpdateIntervalSpinner.Value = 0.05;
            end
            if value > app.XRangeSpinner.Value
                app.UpdateIntervalSpinner.Value = app.XRangeSpinner.Value;
            end
        end

        % Value changed function: VoltageLimtDropDown
        function VoltageLimtDropDownValueChanged(app, event)
            if isempty(app.AnalogInputListBox.Value)
                app.VoltageLimtDropDown.Value = app.VoltageLimtDropDown_2.Value;
            end
            value = app.VoltageLimtDropDown.Value;
            value1 = app.VoltageLimtDropDown_2.Value;
            app.YLowerSpinner.Value = min(app.AI_Subsystem.RangesAvailable([value value1]).Min);
            app.VoltageMin = app.YLowerSpinner.Value;
            app.YUpperSpinner.Value = max(app.AI_Subsystem.RangesAvailable([value value1]).Max);
            app.VoltageMax = app.YUpperSpinner.Value;
            app.UIAxes.YLim = [app.YLowerSpinner.Value app.YUpperSpinner.Value];
            app.UIAxes_2.YLim = [app.YLowerSpinner.Value app.YUpperSpinner.Value];
            app.UIAxes_3.YLim = [app.YLowerSpinner.Value app.YUpperSpinner.Value];
            app.UIAxes_4.YLim = [app.YLowerSpinner.Value app.YUpperSpinner.Value];
        end

        % Value changed function: AnalogInputListBox_2
        function AnalogInputListBox_2ValueChanged(app, event)
            if isempty(app.AnalogInputListBox_2.Value)
                app.AnalogInputListBox_2.Value = [];
            else
                app.AnalogInputListBox_2.Value = sort(app.AnalogInputListBox_2.Value, 'ascend');
            end
            AnalogInputListBoxValueChanged(app, event);
        end

        % Value changed function: VoltageLimtDropDown_2
        function VoltageLimtDropDown_2ValueChanged(app, event)
            if isempty(app.AnalogInputListBox_2.Value)
                app.VoltageLimtDropDown_2.Value = app.VoltageLimtDropDown.Value;
            end
            VoltageLimtDropDownValueChanged(app, event);
        end

        % Value changed function: DigitalInputListBox
        function DigitalInputListBoxValueChanged(app, event)
            value = app.DigitalInputListBox.Value;
            if isempty(value)
                app.DigitalInputListBox.Value = [];
            else
                app.DigitalInputListBox.Value = sort(value, 'ascend');
            end
        end

        % Value changed function: PeekSignalButton
        function PeekSignalButtonValueChanged(app, event)
            if ~app.SelectChannelsButton.Value
                msgbox('No channel selected.', 'HELP', 'help');
                app.PeekSignalButton.Value = 0;
                return;
            end
            value = app.PeekSignalButton.Value;
            if value
                app.StartRecordingButton.Enable = 'Off';
            else
                app.StartRecordingButton.Enable = 'On';
            end
            StartRecordingButtonValueChanged(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VoltageRecordingV1UIFigure = uifigure('Visible', 'off');
            app.VoltageRecordingV1UIFigure.Name = 'Voltage Recording V1';
            app.DataDirectoryButton = uibutton(app.VoltageRecordingV1UIFigure, 'push');
            app.DataDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DataDirectoryButtonPushed, true);
            app.DataDirectoryButton.Text = 'Data Directory';
            app.EditField = uieditfield(app.VoltageRecordingV1UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.DevicesListBoxLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.DevicesListBoxLabel.Text = 'Devices';
            app.DevicesListBox = uilistbox(app.VoltageRecordingV1UIFigure);
            app.DevicesListBox.Items = {};
            app.DevicesListBox.ValueChangedFcn = createCallbackFcn(app, @DevicesListBoxValueChanged, true);
            app.DevicesListBox.Value = {};
            app.AnalogInputListBox_3Label = uilabel(app.VoltageRecordingV1UIFigure);
            app.AnalogInputListBox_3Label.Text = 'Analog Input';
            app.AnalogInputListBox = uilistbox(app.VoltageRecordingV1UIFigure);
            app.AnalogInputListBox.Items = {};
            app.AnalogInputListBox.Multiselect = 'on';
            app.AnalogInputListBox.ValueChangedFcn = createCallbackFcn(app, @AnalogInputListBoxValueChanged, true);
            app.AnalogInputListBox.Value = {};
            app.DigitalInputListBoxLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.DigitalInputListBoxLabel.Text = 'Digital Input';
            app.DigitalInputListBox = uilistbox(app.VoltageRecordingV1UIFigure);
            app.DigitalInputListBox.Items = {};
            app.DigitalInputListBox.Multiselect = 'on';
            app.DigitalInputListBox.ValueChangedFcn = createCallbackFcn(app, @DigitalInputListBoxValueChanged, true);
            app.DigitalInputListBox.Value = {};
            app.UIAxes = uiaxes(app.VoltageRecordingV1UIFigure);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.UIAxes.XLim = [0 4];
            app.UIAxes.YLim = [-10 10];
            app.SelectChannelsButton = uibutton(app.VoltageRecordingV1UIFigure, 'state');
            app.SelectChannelsButton.ValueChangedFcn = createCallbackFcn(app, @SelectChannelsButtonValueChanged, true);
            app.SelectChannelsButton.Text = 'Select Channels';
            app.SampleRateEditFieldLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.VoltageRecordingV1UIFigure, 'numeric');
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateEditFieldValueChanged, true);
            app.SampleRateEditField.Editable = 'off';
            app.SampleRateEditField.Value = Inf;
            app.BitEditFieldLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.BitEditFieldLabel.Text = 'Bit';
            app.BitEditField = uieditfield(app.VoltageRecordingV1UIFigure, 'numeric');
            app.BitEditField.Editable = 'off';
            app.BitEditField.Value = Inf;
            app.HzLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.HzLabel.Text = 'Hz';
            app.SessionEditFieldLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.VoltageRecordingV1UIFigure, 'numeric');
            app.SessionEditField.Editable = 'off';
            app.SessionEditField.Value = 1;
            app.StartRecordingButton = uibutton(app.VoltageRecordingV1UIFigure, 'state');
            app.StartRecordingButton.ValueChangedFcn = createCallbackFcn(app, @StartRecordingButtonValueChanged, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.Lamp = uilamp(app.VoltageRecordingV1UIFigure);
            app.UIAxes_2 = uiaxes(app.VoltageRecordingV1UIFigure);
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Voltage (V)')
            app.UIAxes_2.XLim = [0 4];
            app.UIAxes_2.YLim = [-10 10];
            app.UIAxes_3 = uiaxes(app.VoltageRecordingV1UIFigure);
            xlabel(app.UIAxes_3, 'Time (s)')
            ylabel(app.UIAxes_3, 'Voltage (V)')
            app.UIAxes_3.XLim = [0 4];
            app.UIAxes_3.YLim = [-10 10];
            app.UIAxes_4 = uiaxes(app.VoltageRecordingV1UIFigure);
            xlabel(app.UIAxes_4, 'Time (s)')
            ylabel(app.UIAxes_4, 'Voltage (V)')
            app.UIAxes_4.XLim = [0 4];
            app.UIAxes_4.YLim = [-10 10];
            app.XRangeSpinnerLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.XRangeSpinnerLabel.Text = 'X Range';
            app.XRangeSpinner = uispinner(app.VoltageRecordingV1UIFigure);
            app.XRangeSpinner.Step = 0.5;
            app.XRangeSpinner.ValueChangedFcn = createCallbackFcn(app, @XRangeSpinnerValueChanged, true);
            app.XRangeSpinner.Value = 4;
            app.WindowDisplaySwitchLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.WindowDisplaySwitchLabel.Text = 'Window Display';
            app.WindowDisplaySwitch = uiswitch(app.VoltageRecordingV1UIFigure, 'toggle');
            app.WindowDisplaySwitch.Value = 'On';
            app.YLowerSpinnerLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.YLowerSpinnerLabel.Text = 'Y Lower';
            app.YLowerSpinner = uispinner(app.VoltageRecordingV1UIFigure);
            app.YLowerSpinner.Step = 0.5;
            app.YLowerSpinner.ValueChangedFcn = createCallbackFcn(app, @YLowerSpinnerValueChanged, true);
            app.YLowerSpinner.Enable = 'off';
            app.YLowerSpinner.Value = -Inf;
            app.YUpperSpinnerLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.YUpperSpinnerLabel.Text = 'Y Upper';
            app.YUpperSpinner = uispinner(app.VoltageRecordingV1UIFigure);
            app.YUpperSpinner.Step = 0.5;
            app.YUpperSpinner.ValueChangedFcn = createCallbackFcn(app, @YUpperSpinnerValueChanged, true);
            app.YUpperSpinner.Enable = 'off';
            app.YUpperSpinner.Value = Inf;
            app.VLabel_2 = uilabel(app.VoltageRecordingV1UIFigure);
            app.VLabel_2.Text = 'V';
            app.VLabel_3 = uilabel(app.VoltageRecordingV1UIFigure);
            app.VLabel_3.Text = 'V';
            app.sLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.sLabel.Text = 's';
            app.UpdateIntervalSpinnerLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.UpdateIntervalSpinnerLabel.Text = 'Update Interval';
            app.UpdateIntervalSpinner = uispinner(app.VoltageRecordingV1UIFigure);
            app.UpdateIntervalSpinner.Step = 0.01;
            app.UpdateIntervalSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateIntervalSpinnerValueChanged, true);
            app.UpdateIntervalSpinner.Value = 0.05;
            app.sLabel_2 = uilabel(app.VoltageRecordingV1UIFigure);
            app.sLabel_2.Text = 's';
            app.VoltageLimtDropDownLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.VoltageLimtDropDownLabel.Text = 'Voltage Limt';
            app.VoltageLimtDropDown = uidropdown(app.VoltageRecordingV1UIFigure);
            app.VoltageLimtDropDown.Items = {};
            app.VoltageLimtDropDown.ValueChangedFcn = createCallbackFcn(app, @VoltageLimtDropDownValueChanged, true);
            app.VoltageLimtDropDown.Enable = 'off';
            app.VoltageLimtDropDown.Value = {};
            app.AnalogInputListBox_2Label = uilabel(app.VoltageRecordingV1UIFigure);
            app.AnalogInputListBox_2Label.Text = 'Analog Input';
            app.AnalogInputListBox_2 = uilistbox(app.VoltageRecordingV1UIFigure);
            app.AnalogInputListBox_2.Items = {};
            app.AnalogInputListBox_2.Multiselect = 'on';
            app.AnalogInputListBox_2.ValueChangedFcn = createCallbackFcn(app, @AnalogInputListBox_2ValueChanged, true);
            app.AnalogInputListBox_2.Value = {};
            app.SingleEndedLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.SingleEndedLabel.Text = 'SingleEnded';
            app.DifferentialLabel = uilabel(app.VoltageRecordingV1UIFigure);
            app.DifferentialLabel.Text = 'Differential';
            app.VoltageLimtDropDown_2Label = uilabel(app.VoltageRecordingV1UIFigure);
            app.VoltageLimtDropDown_2Label.Text = 'Voltage Limt';
            app.VoltageLimtDropDown_2 = uidropdown(app.VoltageRecordingV1UIFigure);
            app.VoltageLimtDropDown_2.Items = {};
            app.VoltageLimtDropDown_2.ValueChangedFcn = createCallbackFcn(app, @VoltageLimtDropDown_2ValueChanged, true);
            app.VoltageLimtDropDown_2.Enable = 'off';
            app.VoltageLimtDropDown_2.Value = {};
            app.LegendCheckBox = uicheckbox(app.VoltageRecordingV1UIFigure);
            app.LegendCheckBox.Text = 'Legend';
            app.LegendCheckBox.Value = true;
            app.PeekSignalButton = uibutton(app.VoltageRecordingV1UIFigure, 'state');
            app.PeekSignalButton.ValueChangedFcn = createCallbackFcn(app, @PeekSignalButtonValueChanged, true);
            app.PeekSignalButton.Text = 'Peek Signal';
            % Show the figure after all components are created
            app.VoltageRecordingV1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Acquisition_NIDAQ_2018b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VoltageRecordingV1UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VoltageRecordingV1UIFigure)
        end
    end
end