classdef Free_Moving_Activation_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FreeMovingActivationAnalysisUIFigure  matlab.ui.Figure
        ExpFolderButton             matlab.ui.control.Button
        EditField                   matlab.ui.control.EditField
        VideosListBoxLabel          matlab.ui.control.Label
        VideosListBox               matlab.ui.control.ListBox
        UIAxes                      matlab.ui.control.UIAxes
        Slider                      matlab.ui.control.Slider
        UITable                     matlab.ui.control.Table
        PlayButton                  matlab.ui.control.StateButton
        Lamp                        matlab.ui.control.Lamp
        PlayBackButton              matlab.ui.control.StateButton
        FirstFrameButton            matlab.ui.control.Button
        NextFrameButton             matlab.ui.control.Button
        LastFrameButton             matlab.ui.control.Button
        WriteVideoButton            matlab.ui.control.Button
        FrameRateEditFieldLabel     matlab.ui.control.Label
        FrameRateEditField          matlab.ui.control.NumericEditField
        BrightnessEditFieldLabel    matlab.ui.control.Label
        BrightnessEditField         matlab.ui.control.NumericEditField
        ManualCorrectDropDown       matlab.ui.control.DropDown
        TrackingDataCheckBox        matlab.ui.control.CheckBox
        sLabel                      matlab.ui.control.Label
        GotoEditFieldLabel          matlab.ui.control.Label
        GotoEditField               matlab.ui.control.NumericEditField
        timerangesEditFieldLabel    matlab.ui.control.Label
        timerangesEditField         matlab.ui.control.NumericEditField
        toEditFieldLabel            matlab.ui.control.Label
        toEditField                 matlab.ui.control.NumericEditField
        ManualCorrectButton         matlab.ui.control.Button
        PreEditFieldLabel           matlab.ui.control.Label
        PreEditField                matlab.ui.control.NumericEditField
        PostEditFieldLabel          matlab.ui.control.Label
        PostEditField               matlab.ui.control.NumericEditField
        Plot2DtrajectoryButton      matlab.ui.control.Button
        PlotrelativedistanceButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Exp_Path = 0; % Description
        FrameRate
        sqsize = 100;
        him % Description
        readerobj % Description
        csvdata % Description
        currenttime % Description
        interval % Description
        hrect % Description
        num_track = []; % Description
        dotsize = 3;
        alphavalue = 0.7;
        labelcolor = jet(256)*2^8;
        label_trace = 1; % for showing tracked points in video default 1 or 12
    end
    
    methods (Access = private)
        function generate_content(app, readerobj, num_track, UIAxes, csvdata)
            im = readFrame(readerobj);
            brightness = app.BrightnessEditField.Value;
            if brightness ~= 1
                im = rgb2hsv(im);
                intensity = im(:, :, 3);
                intensity = mat2gray(intensity, [0 brightness]);
                im(:, :, 3) = intensity;
                im = hsv2rgb(im);
            end
            [im, frameID] = draw_labels(app, num_track, im, readerobj);
            app.him = image(im, 'Parent', UIAxes);
            app.hrect = [];
                
            generate_square(app, csvdata, UIAxes, frameID);
        end
        
        function [im, frameID] = draw_labels(app, num_track, im, readerobj)
            if strcmp(readerobj.Name(end-2:end), 'avi')
                frameID = round(readerobj.CurrentTime*readerobj.FrameRate);
            elseif strcmp(readerobj.Name(end-2:end), 'mp4')
                frameID = round((readerobj.CurrentTime-1/readerobj.FrameRate)*readerobj.FrameRate);
            end
            brightness = app.BrightnessEditField.Value;
            if isempty(num_track)
                return;
            end
            label_table = table2array(app.UITable.Data);
            nlabels = size(label_table, 1);
            for i = 1:nlabels
                try % for head-fixed stimulation videos
                    if label_table(i, 2) && num_track(frameID, i*3+1) > label_table(i, 1)
                        if brightness == 1
                            im = insertShape(im, 'FilledCircle', [num_track(frameID, i*3-1) num_track(frameID, i*3) app.dotsize], 'Color',...
                                app.labelcolor(round((i-1)*255/(nlabels-1))+1, :), 'Opacity', app.alphavalue);
                        else
                            im = insertShape(im, 'FilledCircle', [num_track(frameID, i*3-1) num_track(frameID, i*3) app.dotsize], 'Color',...
                                app.labelcolor(round((i-1)*255/(nlabels-1))+1, :)/2^8, 'Opacity', app.alphavalue);
                        end
                    end
                end
            end
        end
        
        function generate_square(app, csvdata, UIAxes, frameID)
            hrect = app.hrect;
            if csvdata(frameID, 1) && isempty(hrect)
                hrect = rectangle(UIAxes, 'Position', [0 0 app.sqsize app.sqsize], 'FaceColor', [1 1 1], 'EdgeColor', 'none');
            end
            if ~isempty(hrect) && ~csvdata(frameID, 1)
                delete(hrect);
                hrect = [];
            end
            app.hrect = hrect;
        end
        
        function update_content(app, readerobj, num_track, UIAxes, csvdata)
            im = readFrame(readerobj);
            brightness = app.BrightnessEditField.Value;
            if brightness ~= 1
                im = rgb2hsv(im);
                intensity = im(:, :, 3);
                intensity = mat2gray(intensity, [0 brightness]);
                im(:, :, 3) = intensity;
                im = hsv2rgb(im);
            end
            [im, frameID] = draw_labels(app, num_track, im, readerobj);
            app.him.CData = im;
            generate_square(app, csvdata, UIAxes, frameID);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes, 'image');
            app.UIAxes.Visible = 'off';
            app.FrameRate = app.FrameRateEditField.Value;
        end

        % Button pushed function: ExpFolderButton
        function ExpFolderButtonPushed(app, event)
            app.VideosListBox.Enable = 1;
            app.NextFrameButton.Enable = 1;
            app.LastFrameButton.Enable = 1;
            curpwd = pwd;
            Path_change = 0;
            try
                Exp_Path = uigetdir(app.Exp_Path, 'Pick a folder');
            catch
                Exp_Path = uigetdir('', 'Pick a folder');
            end
            if Exp_Path ~= 0
                app.Exp_Path = Exp_Path;
                Path_change = 1;
            end
            if Path_change
                app.EditField.Value = app.Exp_Path;
                app.VideosListBox.Items = cell(0);
                app.VideosListBox.ItemsData = [];
                trialID = [];
                cd(app.Exp_Path);
                DirList = dir;
                for i = 3:size(DirList)
                    if numel(DirList(i).name) >= 14
                        if strcmp(DirList(i).name(1:2), 'PG') && strcmp(DirList(i).name(4:9), 'camera') &&...
                                (strcmp(DirList(i).name(end-3:end), '.avi') || strcmp(DirList(i).name(end-3:end), '.mp4'))
                            if ~isnan(str2double(DirList(i).name(10:end-4)))
                                trialID = [trialID str2double(DirList(i).name(10:end-4))];
                                app.VideosListBox.Items(end+1) = {DirList(i).name};
                            end
                        end
                    end
                end
                [~, video_order] = sort(trialID);
                app.VideosListBox.Items = app.VideosListBox.Items(video_order);
                app.VideosListBox.ItemsData = 1:numel(app.VideosListBox.Items);
                
                % make the tracking table
                camtrack = find_tracking_filename(app.Exp_Path, app.VideosListBox.Items{2}(1:end-4));
                [num_track, ~, ~] = xlsread(camtrack);
                switch (size(num_track, 2)-1)/3
                    case 3
                        app.UITable.RowName = {'PawL', 'PawR', 'Jaw'};
                        showup = [true, true, true]';
                        
                        app.ManualCorrectDropDown.Items = app.UITable.RowName;
                        app.ManualCorrectDropDown.ItemsData = 1:numel(app.ManualCorrectDropDown.Items);
                        app.ManualCorrectDropDown.Value = 2;
                    case 8
                        app.UITable.RowName = {'EyeL', 'EyeR', 'NoseTip', 'PawL', 'PawR', 'AnkleL', 'AnkleR', 'Tail'};
                        showup = [true, true, true, true, true, true, true, true]';
                        
                        app.ManualCorrectDropDown.Items = app.UITable.RowName;
                        app.ManualCorrectDropDown.ItemsData = 1:numel(app.ManualCorrectDropDown.Items);
                        app.ManualCorrectDropDown.Value = 4;
                    case 33
                        app.UITable.RowName = {'EyeL', 'EyeR', 'NoseTip', 'Tongue', 'ThumbL', 'IndexL', 'MiddleL', 'RingL', 'LittleL', 'PawL', 'ThumbR',...
                            'IndexR', 'MiddleR', 'RingR', 'LittleR', 'PawR', 'AnkleL', 'AnkleR', 'Tail', 'Pellet', 'AHTop', 'AHBottom', 'CubeCenter',...
                            'ATop', 'ABottom', 'BLeft', 'BRight', 'CLeft', 'CRight', 'DLeft', 'DRight', 'Jaw', 'AHCenter'};
                        showup = [true, true, true, false, false, false, false, false, false, true, false,...
                            false, false, false, false, true, true, true, true, false, true, true, false,...
                            false, false, false, false, false, false, false, false, true, true]';
                        
                        app.ManualCorrectDropDown.Items = app.UITable.RowName;
                        app.ManualCorrectDropDown.ItemsData = 1:numel(app.ManualCorrectDropDown.Items);
                        app.ManualCorrectDropDown.Value = 10;
                end
                app.UITable.ColumnName = {'pcutoff', 'showup'};
                pcutoff = ones(numel(app.UITable.RowName), 1)*0;
                app.UITable.Data = table(pcutoff, showup);
            end
            cd(curpwd);
            figure(app.FreeMovingActivationAnalysisUIFigure);
        end

        % Value changed function: VideosListBox
        function VideosListBoxValueChanged(app, event)
            value = app.VideosListBox.Value;
            app.VideosListBox.Enable = 0;
            
            if numel(value) == 1
                app.readerobj = VideoReader([app.Exp_Path '\' app.VideosListBox.Items{value}]);
                app.csvdata = load([app.Exp_Path '\' app.VideosListBox.Items{value}(1:3) 'gpio' app.VideosListBox.Items{value}(10:end-4) '.csv']);
                
                if app.TrackingDataCheckBox.Value
                    camtrack = find_tracking_filename(app.Exp_Path, app.VideosListBox.Items{value}(1:end-4));
                    if ~isempty(camtrack)
                        [num_track, ~, ~] = xlsread(camtrack);
                    end
                    app.num_track = num_track;
                end
                
                cla(app.UIAxes);
                app.hrect = [];
                app.currenttime = inf;
                
                generate_content(app, app.readerobj, app.num_track, app.UIAxes, app.csvdata);
                if strcmp(app.readerobj.Name(end-2:end), 'avi')
                    app.currenttime = min(app.currenttime, app.readerobj.CurrentTime);
                elseif strcmp(app.readerobj.Name(end-2:end), 'mp4')
                    app.currenttime = min(app.currenttime, app.readerobj.CurrentTime-1/app.readerobj.FrameRate);
                end

                app.interval = 1/app.readerobj.FrameRate;
                duration = app.readerobj.Duration;
                
                app.Slider.Limits = [app.interval duration];
                app.Slider.Value = app.currenttime;
                app.GotoEditField.Limits = app.Slider.Limits;
                app.GotoEditField.Value = app.Slider.Value;
                if app.toEditField.Value > duration
                    app.toEditField.Value = duration;
                end
            end
            app.VideosListBox.Enable = 1;
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value
                app.Lamp.Color = [0 1 0];
                if strcmp(app.readerobj.Name(end-2:end), 'avi')
                    app.readerobj.CurrentTime = app.currenttime;
                elseif strcmp(app.readerobj.Name(end-2:end), 'mp4')
                    app.readerobj.CurrentTime = app.currenttime+1/app.readerobj.FrameRate;
                end
                interval = 1/app.FrameRate;
            end
            while app.PlayButton.Value && app.currenttime <= app.Slider.Limits(2)-app.interval
                time0 = tic;
                app.currenttime = inf;
                update_content(app, app.readerobj, app.num_track, app.UIAxes, app.csvdata);
                if strcmp(app.readerobj.Name(end-2:end), 'avi')
                    app.currenttime = min(app.currenttime, app.readerobj.CurrentTime);
                elseif strcmp(app.readerobj.Name(end-2:end), 'mp4')
                    app.currenttime = min(app.currenttime, app.readerobj.CurrentTime-1/app.readerobj.FrameRate);
                end
                
                app.Slider.Value = app.currenttime;
                app.GotoEditField.Value = app.Slider.Value;
                drawnow limitrate;
                while toc(time0) < interval
                end
            end
            app.Lamp.Color = [1 1 1];
            app.PlayButton.Value = 0;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.currenttime = value;
            if strcmp(app.readerobj.Name(end-2:end), 'avi')
                app.readerobj.CurrentTime = app.currenttime-1/app.readerobj.FrameRate;
            elseif strcmp(app.readerobj.Name(end-2:end), 'mp4')
                app.readerobj.CurrentTime = app.currenttime;
            end
            update_content(app, app.readerobj, app.num_track, app.UIAxes, app.csvdata);
            app.GotoEditField.Value = app.Slider.Value;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.currenttime = changingValue;
            if strcmp(app.readerobj.Name(end-2:end), 'avi')
                app.readerobj.CurrentTime = app.currenttime-1/app.readerobj.FrameRate;
            elseif strcmp(app.readerobj.Name(end-2:end), 'mp4')
                app.readerobj.CurrentTime = app.currenttime;
            end
            update_content(app, app.readerobj, app.num_track, app.UIAxes, app.csvdata);
        end

        % Value changed function: PlayBackButton
        function PlayBackButtonValueChanged(app, event)
            value = app.PlayBackButton.Value;
            if value
                app.Lamp.Color = [0 1 0];
                interval = 1/app.FrameRate;
                jumptime = 2*app.interval;
            end
            while app.PlayBackButton.Value && app.currenttime-jumptime >= 0
                time0 = tic;
                currenttime = 0;
                if strcmp(app.readerobj.Name(end-2:end), 'avi')
                    app.readerobj.CurrentTime = app.currenttime-2/app.readerobj.FrameRate;
                elseif strcmp(app.readerobj.Name(end-2:end), 'mp4')
                    app.readerobj.CurrentTime = app.currenttime-1/app.readerobj.FrameRate;
                end
                update_content(app, app.readerobj, app.num_track, app.UIAxes, app.csvdata);
                if strcmp(app.readerobj.Name(end-2:end), 'avi')
                    currenttime = max(currenttime, app.readerobj.CurrentTime);
                elseif strcmp(app.readerobj.Name(end-2:end), 'mp4')
                    currenttime = max(currenttime, app.readerobj.CurrentTime-1/app.readerobj.FrameRate);
                end
                app.currenttime = currenttime;
                
                app.Slider.Value = app.currenttime;
                app.GotoEditField.Value = app.Slider.Value;
                drawnow limitrate;
                while toc(time0) < interval
                end
            end
            app.Lamp.Color = [1 1 1];
            app.PlayBackButton.Value = 0;
        end

        % Button pushed function: FirstFrameButton
        function FirstFrameButtonPushed(app, event)
            app.Slider.Value = app.Slider.Limits(1);
            SliderValueChanged(app, event);
            app.GotoEditField.Value = app.Slider.Value;
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            if app.currenttime <= app.Slider.Limits(2)-app.interval
                app.NextFrameButton.Enable = 0;
                app.currenttime = inf;
                update_content(app, app.readerobj, app.num_track, app.UIAxes, app.csvdata);
                if strcmp(app.readerobj.Name(end-2:end), 'avi')
                    app.currenttime = min(app.currenttime, app.readerobj.CurrentTime);
                elseif strcmp(app.readerobj.Name(end-2:end), 'mp4')
                    app.currenttime = min(app.currenttime, app.readerobj.CurrentTime-1/app.readerobj.FrameRate);
                end
                app.Slider.Value = app.currenttime;
                app.GotoEditField.Value = app.Slider.Value;
                app.NextFrameButton.Enable = 1;
            end
        end

        % Button pushed function: LastFrameButton
        function LastFrameButtonPushed(app, event)
            jumptime = 2*app.interval;
            if app.currenttime-jumptime >= 0
                app.LastFrameButton.Enable = 0;
                currenttime = 0;
                if strcmp(app.readerobj.Name(end-2:end), 'avi')
                    app.readerobj.CurrentTime = app.currenttime-2/app.readerobj.FrameRate;
                elseif strcmp(app.readerobj.Name(end-2:end), 'mp4')
                    app.readerobj.CurrentTime = app.currenttime-1/app.readerobj.FrameRate;
                end
                update_content(app, app.readerobj, app.num_track, app.UIAxes, app.csvdata);
                if strcmp(app.readerobj.Name(end-2:end), 'avi')
                    currenttime = max(currenttime, app.readerobj.CurrentTime);
                elseif strcmp(app.readerobj.Name(end-2:end), 'mp4')
                    currenttime = max(currenttime, app.readerobj.CurrentTime-1/app.readerobj.FrameRate);
                end
                app.currenttime = currenttime;
                app.Slider.Value = app.currenttime;
                app.GotoEditField.Value = app.Slider.Value;
                app.LastFrameButton.Enable = 1;
            end
        end

        % Value changed function: TrackingDataCheckBox
        function TrackingDataCheckBoxValueChanged(app, event)
            videoID = app.VideosListBox.Value;
            if numel(videoID) ~= 1
                app.TrackingDataCheckBox.Value = 0;
                return;
            end
            value = app.TrackingDataCheckBox.Value;
            app.TrackingDataCheckBox.Enable = 0;
            drawnow;
            if value
                app.UITable.Enable = 'on';
                camtrack = find_tracking_filename(app.Exp_Path, app.VideosListBox.Items{videoID}(1:end-4));
                if ~isempty(camtrack)
                    [num_track, ~, ~] = xlsread(camtrack);
                end
                app.num_track = num_track;
                UITableCellEdit(app, event);
            else
                app.UITable.Enable = 'off';
                app.num_track = [];
                UITableCellEdit(app, event);
            end
            app.TrackingDataCheckBox.Enable = 1;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            if ~isempty(app.VideosListBox.Value)
                if strcmp(app.readerobj.Name(end-2:end), 'avi')
                    app.readerobj.CurrentTime = app.currenttime-1/app.readerobj.FrameRate;
                elseif strcmp(app.readerobj.Name(end-2:end), 'mp4')
                    app.readerobj.CurrentTime = app.currenttime;
                end
                generate_content(app, app.readerobj, app.num_track, app.UIAxes, app.csvdata);
            end
        end

        % Value changed function: GotoEditField
        function GotoEditFieldValueChanged(app, event)
            value = app.GotoEditField.Value;
            app.Slider.Value = value;
            SliderValueChanged(app, event);
        end

        % Value changed function: BrightnessEditField
        function BrightnessEditFieldValueChanged(app, event)
            if ~isempty(app.VideosListBox.Value)
                if strcmp(app.readerobj.Name(end-2:end), 'avi')
                    app.readerobj.CurrentTime = app.currenttime-1/app.readerobj.FrameRate;
                elseif strcmp(app.readerobj.Name(end-2:end), 'mp4')
                    app.readerobj.CurrentTime = app.currenttime;
                end
                generate_content(app, app.readerobj, app.num_track, app.UIAxes, app.csvdata);
            end
        end

        % Key press function: FreeMovingActivationAnalysisUIFigure
        function FreeMovingActivationAnalysisUIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case {'d', 'rightarrow'}
                    if app.NextFrameButton.Enable && app.LastFrameButton.Enable
                        NextFrameButtonPushed(app, event);
                    end
                case {'a', 'leftarrow'}
                    if app.LastFrameButton.Enable && app.NextFrameButton.Enable
                        LastFrameButtonPushed(app, event);
                    end
                case {'s', 'downarrow'}
                    value = app.VideosListBox.Value;
                    if value ~= numel(app.VideosListBox.ItemsData)
                        app.VideosListBox.Value = value+1;
                    end
                    VideosListBoxValueChanged(app, event);
                case {'w', 'uparrow'}
                    value = app.VideosListBox.Value;
                    if value ~= 1
                        app.VideosListBox.Value = value-1;
                    end
                    VideosListBoxValueChanged(app, event);
                case 'f'
                    if app.NextFrameButton.Enable && app.LastFrameButton.Enable
                        for i = 1:6
                            NextFrameButtonPushed(app, event);
                            pause(0.05);
                        end
                    end
                case 'c'
                    app.PlayButton.Value = ~app.PlayButton.Value;
                    PlayButtonValueChanged(app, event);
            end
        end

        % Button pushed function: ManualCorrectButton
        function ManualCorrectButtonPushed(app, event)
            if ~app.TrackingDataCheckBox.Value
                return;
            end
            app.TrackingDataCheckBox.Value = 0;
            TrackingDataCheckBoxValueChanged(app, event);
            figure;
            imshow(app.him.CData);
            [x, y] = ginput(1);
            close(gcf);
            
            videoID = app.VideosListBox.Value;
            camtrack = find_tracking_filename(app.Exp_Path, app.VideosListBox.Items{videoID}(1:end-4));
            if ~isempty(camtrack)
                [~, ~, raw_track] = xlsread(camtrack);
            end
            
            if strcmp(app.readerobj.Name(end-2:end), 'avi')
                frameID = round(app.readerobj.CurrentTime*app.readerobj.FrameRate);
            elseif strcmp(app.readerobj.Name(end-2:end), 'mp4')
                frameID = round((app.readerobj.CurrentTime-1/app.readerobj.FrameRate)*app.readerobj.FrameRate);
            end
            raw_track(frameID+3, app.ManualCorrectDropDown.Value*3-1:app.ManualCorrectDropDown.Value*3+1) = {x y 1};
            writecell(raw_track, camtrack);
            
            app.TrackingDataCheckBox.Value = 1;
            TrackingDataCheckBoxValueChanged(app, event);
        end

        % Button pushed function: Plot2DtrajectoryButton
        function Plot2DtrajectoryButtonPushed(app, event)
            pre = app.PreEditField.Value;
            post = app.PostEditField.Value;
            Plot2Dtrajectory(app.csvdata, app.num_track, app.UITable.Data, pre, post);
        end

        % Button pushed function: PlotrelativedistanceButton
        function PlotrelativedistanceButtonPushed(app, event)
            pre = app.PreEditField.Value;
            post = app.PostEditField.Value;
            FrameRate =app.readerobj.FrameRate ;
            plot_relative_distance(app, app.Exp_Path, FrameRate, pre, post);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FreeMovingActivationAnalysisUIFigure = uifigure('Visible', 'off');
            app.FreeMovingActivationAnalysisUIFigure.Name = 'FreeMovingActivationAnalysis';
            app.FreeMovingActivationAnalysisUIFigure.KeyPressFcn = createCallbackFcn(app, @FreeMovingActivationAnalysisUIFigureKeyPress, true);
            app.ExpFolderButton = uibutton(app.FreeMovingActivationAnalysisUIFigure, 'push');
            app.ExpFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ExpFolderButtonPushed, true);
            app.ExpFolderButton.Text = 'Exp Folder';
            app.EditField = uieditfield(app.FreeMovingActivationAnalysisUIFigure, 'text');
            app.EditField.Editable = 'off';
            app.VideosListBoxLabel = uilabel(app.FreeMovingActivationAnalysisUIFigure);
            app.VideosListBoxLabel.Text = 'Videos';
            app.VideosListBox = uilistbox(app.FreeMovingActivationAnalysisUIFigure);
            app.VideosListBox.Items = {};
            app.VideosListBox.Multiselect = 'on';
            app.VideosListBox.ValueChangedFcn = createCallbackFcn(app, @VideosListBoxValueChanged, true);
            app.VideosListBox.Value = {};
            app.UIAxes = uiaxes(app.FreeMovingActivationAnalysisUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Slider = uislider(app.FreeMovingActivationAnalysisUIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.UITable = uitable(app.FreeMovingActivationAnalysisUIFigure);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Enable = 'off';
            app.PlayButton = uibutton(app.FreeMovingActivationAnalysisUIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.Lamp = uilamp(app.FreeMovingActivationAnalysisUIFigure);
            app.PlayBackButton = uibutton(app.FreeMovingActivationAnalysisUIFigure, 'state');
            app.PlayBackButton.ValueChangedFcn = createCallbackFcn(app, @PlayBackButtonValueChanged, true);
            app.PlayBackButton.Text = 'Play Back';
            app.FirstFrameButton = uibutton(app.FreeMovingActivationAnalysisUIFigure, 'push');
            app.FirstFrameButton.ButtonPushedFcn = createCallbackFcn(app, @FirstFrameButtonPushed, true);
            app.FirstFrameButton.Text = 'First Frame';
            app.NextFrameButton = uibutton(app.FreeMovingActivationAnalysisUIFigure, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next Frame';
            app.LastFrameButton = uibutton(app.FreeMovingActivationAnalysisUIFigure, 'push');
            app.LastFrameButton.ButtonPushedFcn = createCallbackFcn(app, @LastFrameButtonPushed, true);
            app.LastFrameButton.Text = 'Last Frame';
            app.WriteVideoButton = uibutton(app.FreeMovingActivationAnalysisUIFigure, 'push');
            app.WriteVideoButton.Text = 'Write Video';
            app.FrameRateEditFieldLabel = uilabel(app.FreeMovingActivationAnalysisUIFigure);
            app.FrameRateEditFieldLabel.Text = 'FrameRate';
            app.FrameRateEditField = uieditfield(app.FreeMovingActivationAnalysisUIFigure, 'numeric');
            app.FrameRateEditField.Limits = [0 Inf];
            app.FrameRateEditField.Value = 20;
            app.BrightnessEditFieldLabel = uilabel(app.FreeMovingActivationAnalysisUIFigure);
            app.BrightnessEditFieldLabel.Text = 'Brightness';
            app.BrightnessEditField = uieditfield(app.FreeMovingActivationAnalysisUIFigure, 'numeric');
            app.BrightnessEditField.Limits = [0 1];
            app.BrightnessEditField.ValueChangedFcn = createCallbackFcn(app, @BrightnessEditFieldValueChanged, true);
            app.BrightnessEditField.Value = 1;
            app.ManualCorrectDropDown = uidropdown(app.FreeMovingActivationAnalysisUIFigure);
            app.ManualCorrectDropDown.Items = {};
            app.ManualCorrectDropDown.Value = {};
            app.TrackingDataCheckBox = uicheckbox(app.FreeMovingActivationAnalysisUIFigure);
            app.TrackingDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackingDataCheckBoxValueChanged, true);
            app.TrackingDataCheckBox.Text = 'Tracking Data';
            app.sLabel = uilabel(app.FreeMovingActivationAnalysisUIFigure);
            app.sLabel.Text = 's';
            app.GotoEditFieldLabel = uilabel(app.FreeMovingActivationAnalysisUIFigure);
            app.GotoEditFieldLabel.Text = 'Go to';
            app.GotoEditField = uieditfield(app.FreeMovingActivationAnalysisUIFigure, 'numeric');
            app.GotoEditField.ValueChangedFcn = createCallbackFcn(app, @GotoEditFieldValueChanged, true);
            app.timerangesEditFieldLabel = uilabel(app.FreeMovingActivationAnalysisUIFigure);
            app.timerangesEditFieldLabel.Text = 'time range (s)';
            app.timerangesEditField = uieditfield(app.FreeMovingActivationAnalysisUIFigure, 'numeric');
            app.toEditFieldLabel = uilabel(app.FreeMovingActivationAnalysisUIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.FreeMovingActivationAnalysisUIFigure, 'numeric');
            app.toEditField.Value = 2.5;
            app.ManualCorrectButton = uibutton(app.FreeMovingActivationAnalysisUIFigure, 'push');
            app.ManualCorrectButton.ButtonPushedFcn = createCallbackFcn(app, @ManualCorrectButtonPushed, true);
            app.ManualCorrectButton.Text = 'Manual Correct';
            app.PreEditFieldLabel = uilabel(app.FreeMovingActivationAnalysisUIFigure);
            app.PreEditFieldLabel.Text = 'Pre';
            app.PreEditField = uieditfield(app.FreeMovingActivationAnalysisUIFigure, 'numeric');
            app.PreEditField.Value = 1;
            app.PostEditFieldLabel = uilabel(app.FreeMovingActivationAnalysisUIFigure);
            app.PostEditFieldLabel.Text = 'Post';
            app.PostEditField = uieditfield(app.FreeMovingActivationAnalysisUIFigure, 'numeric');
            app.PostEditField.Value = 1;
            app.Plot2DtrajectoryButton = uibutton(app.FreeMovingActivationAnalysisUIFigure, 'push');
            app.Plot2DtrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @Plot2DtrajectoryButtonPushed, true);
            app.Plot2DtrajectoryButton.Text = 'Plot 2D trajectory';
            app.PlotrelativedistanceButton = uibutton(app.FreeMovingActivationAnalysisUIFigure, 'push');
            app.PlotrelativedistanceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotrelativedistanceButtonPushed, true);
            app.PlotrelativedistanceButton.Text = 'Plot relative distance';
            % Show the figure after all components are created
            app.FreeMovingActivationAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Free_Moving_Activation_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FreeMovingActivationAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FreeMovingActivationAnalysisUIFigure)
        end
    end
end