classdef Free_Moving_Feeding_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FreemovingFeedingAnalysisUIFigure  matlab.ui.Figure
        ExpFolderButton                matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        TrialsListBoxLabel             matlab.ui.control.Label
        TrialsListBox                  matlab.ui.control.ListBox
        VideoPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        Slider                         matlab.ui.control.Slider
        Video1CheckBox                 matlab.ui.control.CheckBox
        Video2CheckBox                 matlab.ui.control.CheckBox
        Video3CheckBox                 matlab.ui.control.CheckBox
        VideoforMarkingButtonGroup     matlab.ui.container.ButtonGroup
        Video1Button                   matlab.ui.control.RadioButton
        Video2Button                   matlab.ui.control.RadioButton
        Video3Button                   matlab.ui.control.RadioButton
        MarkasreadytobiteButton        matlab.ui.control.Button
        MarkasfeedingendButton         matlab.ui.control.Button
        FrameRateEditFieldLabel        matlab.ui.control.Label
        FrameRateEditField             matlab.ui.control.NumericEditField
        MarktrialtodisgardButton       matlab.ui.control.Button
        MarktrialtoincludeButton       matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        BrightnessEditFieldLabel       matlab.ui.control.Label
        BrightnessEditField            matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
        TrackingDataCheckBox           matlab.ui.control.CheckBox
        DropDown                       matlab.ui.control.DropDown
        VisualizeTrajectoryButton      matlab.ui.control.Button
        PickCPFolderButton             matlab.ui.control.Button
        EditField_2                    matlab.ui.control.EditField
        PreprocessTrajectoriesButton   matlab.ui.control.Button
        AverageTrajectoryButton        matlab.ui.control.Button
        timerangesEditFieldLabel       matlab.ui.control.Label
        timerangesEditField            matlab.ui.control.NumericEditField
        toEditFieldLabel               matlab.ui.control.Label
        toEditField                    matlab.ui.control.NumericEditField
        DropDown_2                     matlab.ui.control.DropDown
        toLabel                        matlab.ui.control.Label
        DropDown_3                     matlab.ui.control.DropDown
        VisualizeDistanceButton        matlab.ui.control.Button
        AverageDistanceButton          matlab.ui.control.Button
        AHOrientationButton            matlab.ui.control.Button
        AverageOrientationButton       matlab.ui.control.Button
        PhaseAnalysisButton            matlab.ui.control.Button
        PhaseAnalysisAllButton         matlab.ui.control.Button
        GotoEditFieldLabel             matlab.ui.control.Label
        GotoEditField                  matlab.ui.control.NumericEditField
        sLabel                         matlab.ui.control.Label
        EthogramButton                 matlab.ui.control.Button
        LabelEventsPanel               matlab.ui.container.Panel
        FoodinMouthButton              matlab.ui.control.Button
        PawLReachButton                matlab.ui.control.StateButton
        PawRReachButton                matlab.ui.control.StateButton
        SitButton                      matlab.ui.control.StateButton
        PawLAdjustmentButton           matlab.ui.control.StateButton
        PawRAdjustmentButton           matlab.ui.control.StateButton
        BiteBoutButton                 matlab.ui.control.StateButton
        FeedingEndButton               matlab.ui.control.Button
        SaveFromWorkspaceButton        matlab.ui.control.Button
        MouthOpenButton                matlab.ui.control.StateButton
        TongueOutButton                matlab.ui.control.StateButton
        ToNoseButton                   matlab.ui.control.Button
        EventPlotButton                matlab.ui.control.Button
        CheckEventCheckBox             matlab.ui.control.CheckBox
        Spinner                        matlab.ui.control.Spinner
        DeleteEventCheckBox            matlab.ui.control.CheckBox
        RetrievalStartButton           matlab.ui.control.Button
        MouthRetrievalButton           matlab.ui.control.StateButton
        HMMforBoutStartButton          matlab.ui.control.Button
        HMMforSitButton                matlab.ui.control.Button
        PawAdjAnalysisButton           matlab.ui.control.Button
        EvaluateCheckBox               matlab.ui.control.CheckBox
        EventPropertyButton            matlab.ui.control.Button
        TrajectorytoEventsButton       matlab.ui.control.Button
        DetectDistanceButton           matlab.ui.control.Button
        distmmEditFieldLabel           matlab.ui.control.Label
        thrEditField                   matlab.ui.control.NumericEditField
        FoodDetectionButton            matlab.ui.control.Button
        AligntoBiteButton              matlab.ui.control.Button
        AligntoBiteAllButton           matlab.ui.control.Button
        bitesEditFieldLabel            matlab.ui.control.Label
        bitesEditField                 matlab.ui.control.NumericEditField
        AligntoAdjAllButton            matlab.ui.control.Button
        AudioFiberPhotometryPanel      matlab.ui.container.Panel
        AudioCheckBox                  matlab.ui.control.CheckBox
        UIAxes2                        matlab.ui.control.UIAxes
        SoundCheckBox                  matlab.ui.control.CheckBox
        PlotAudioDataButton            matlab.ui.control.Button
        DetectBitesButton              matlab.ui.control.Button
        ThresholdEditFieldLabel        matlab.ui.control.Label
        ThresholdEditField             matlab.ui.control.NumericEditField
        stdLabel                       matlab.ui.control.Label
        CheckDetecetionCheckBox        matlab.ui.control.CheckBox
        BiteAnalysisButton             matlab.ui.control.Button
        SessionTypeButtonGroup         matlab.ui.container.ButtonGroup
        sDelay4sOnButton               matlab.ui.control.RadioButton
        sDelay4sOnButton_2             matlab.ui.control.RadioButton
        NoLightButton                  matlab.ui.control.RadioButton
        sOnTwiceButton                 matlab.ui.control.RadioButton
        WholeTrialOnButton             matlab.ui.control.RadioButton
        BiteDeviceButton               matlab.ui.control.RadioButton
        CombineSessionsPanel           matlab.ui.container.Panel
        ListBox                        matlab.ui.control.ListBox
        AddfolderButton                matlab.ui.control.Button
        RemoveButton                   matlab.ui.control.Button
        PCAPanel                       matlab.ui.container.Panel
        PCAZaxisButton                 matlab.ui.control.Button
        PCEditFieldLabel               matlab.ui.control.Label
        PCEditField                    matlab.ui.control.NumericEditField
        FPCheckBox                     matlab.ui.control.CheckBox
        RemoveBitesinTimeButton        matlab.ui.control.Button
        FiberPhotometryRecordingPanel  matlab.ui.container.Panel
        UpdateLabelsforLickButton      matlab.ui.control.Button
        EditField_3                    matlab.ui.control.EditField
        SelectTrialsButton             matlab.ui.control.Button
        GCaMPtolickButton              matlab.ui.control.Button
        GCaMPtoBiteButton              matlab.ui.control.Button
        GCaMPtoBiteAllButton           matlab.ui.control.Button
        OmissionCheckBox               matlab.ui.control.CheckBox
        AverageOmissionButton          matlab.ui.control.Button
        GCaMPtoEventsButton            matlab.ui.control.Button
        GCaMPtoTrajectoryAllButton     matlab.ui.control.Button
        presEditFieldLabel             matlab.ui.control.Label
        presEditField                  matlab.ui.control.NumericEditField
        postsEditFieldLabel            matlab.ui.control.Label
        postsEditField                 matlab.ui.control.NumericEditField
        GCaMPtoAllButton               matlab.ui.control.Button
        GCaMPTrajCorrButton            matlab.ui.control.Button
        PlayButton                     matlab.ui.control.StateButton
        Lamp                           matlab.ui.control.Lamp
        PlayBackButton                 matlab.ui.control.StateButton
        FirstFrameButton               matlab.ui.control.Button
        NextFrameButton                matlab.ui.control.Button
        LastFrameButton                matlab.ui.control.Button
        WriteSoundTrackButton          matlab.ui.control.Button
        OptSessionCheckBox             matlab.ui.control.CheckBox
        WriteVideoButton               matlab.ui.control.Button
        CloseFiguresButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        sqsize = 100;
        Exp_Path = 0; % Description
        him1 % Description
        readerobj1 % Description
        csvdata1 % Description
        him2 % Description
        readerobj2 % Description
        csvdata2 % Description
        him3 % Description
        readerobj3 % Description
        csvdata3 % Description
        currenttime % Description
        FrameRate % Description
        maxinterval % Description
        hrect1 % Description
        hrect2 % Description
        hrect3 % Description
        audiodata % Description
        fpdata
        SampleRate = 96000;
        lowpassfreq = 8000;
        hline % Description
        Video_annotation % Description
        highpassfreq = 800; % Description
        standard_delay = 4; % s
        bite_bin = 0.2; % s
        num_track1 = []; % Description
        num_track2 = []; % Description
        num_track3 = []; % Description
        dotsize = 3;
        alphavalue = 0.7;
        labelcolor = jet(256)*2^8;
        label_trace = 1; % for showing tracked points in video default 1 or 12
        nmedian = 12;
        pawsegment = 1; % frames for relative paw angle
        zspeed_threshold = nan; % was 1.9 mm/s (filtered), animal make significant movement above this speed (2 times of ankle speed sd)
        xyzspeed_threshold = nan; % was 25 mm/s (filtered), animal make significant movement above this speed (slightly lower than mean+sd)
        LabelledEvents
    end
    
    methods (Access = private)
        
        function generate_square(app, csvdata, UIAxes, frameID, camID)
            switch camID
                case 1
                    hrect = app.hrect1;
                case 2
                    hrect = app.hrect2;
                case 3
                    hrect = app.hrect3;
            end
            if csvdata(frameID, 1) && isempty(hrect) && app.OptSessionCheckBox.Value
                hrect = rectangle(UIAxes, 'Position', [0 0 app.sqsize app.sqsize], 'FaceColor', [1 1 1], 'EdgeColor', 'none');
            end
            if ~isempty(hrect) && (~csvdata(frameID, 1) || ~app.OptSessionCheckBox.Value)
                delete(hrect);
                hrect = [];
            end
            switch camID
                case 1
                    app.hrect1 = hrect;
                case 2
                    app.hrect2 = hrect;
                case 3
                    app.hrect3 = hrect;
            end
        end
        
        function update_annotations(app)
            Video_annotation = app.Video_annotation;
            save([app.Exp_Path '\Analysis_Session.mat'], 'Video_annotation');
            hmsg = msgbox('Saved !');
            pause(0.1);
            close(hmsg);
        end
        
        function update_LabelledEvents(app)
            LabelledEvents = app.LabelledEvents;
            assignin('base', 'LabelledEvents', LabelledEvents);
            value = app.TrialsListBox.Value;
            save([app.Exp_Path '\LabelledEvents' num2str(value) '.mat'], 'LabelledEvents');
            hmsg = msgbox('Saved !');
            pause(0.1);
            close(hmsg);
        end
        
        function checkORdelete_event(app, event, EventName1, EventName2)
            eventID = app.Spinner.Value;
            nevent = numel(eval(['app.LabelledEvents.' EventName1]));
            if eventID <= nevent
                if ~app.DeleteEventCheckBox.Value
                    eventTime = eval(['app.LabelledEvents.' EventName1 '(eventID)']);
                    app.GotoEditField.Value = eventTime;
                    GotoEditFieldValueChanged(app, event);
                else
                    ButtonName = questdlg(['Are you sure to remove event ' num2str(eventID) ' of ' EventName1 '?']);
                    switch ButtonName
                        case 'Yes'
                            eval(['app.LabelledEvents.' EventName1 '(eventID) = [];']);
                            if ~isempty(EventName2)
                                eval(['app.LabelledEvents.' EventName2 '(eventID) = [];']);
                            end
                            update_LabelledEvents(app);
                            app.DeleteEventCheckBox.Value = 0;
                    end
                end
            else
                warndlg(['There are ' num2str(nevent) ' events for ' EventName1 '.'], 'Warning');
            end
        end
        
        function [im, frameID] = draw_labels(app, num_track, im, readerobj)
            if strcmp(readerobj.Name(end-2:end), 'avi')
                frameID = round(readerobj.CurrentTime*readerobj.FrameRate);
            elseif strcmp(readerobj.Name(end-2:end), 'mp4')
                frameID = round((readerobj.CurrentTime-1/readerobj.FrameRate)*readerobj.FrameRate);
            end
            brightness = app.BrightnessEditField.Value;
            if isempty(num_track)
                return;
            end
            label_table = table2array(app.UITable.Data);
            nlabels = size(label_table, 1);
            for i = 1:nlabels
                if label_table(i, 2) && num_track(frameID, i*3+1) > label_table(i, 1)
                    if brightness == 1
                        im = insertShape(im, 'FilledCircle', [num_track(frameID, i*3-1) num_track(frameID, i*3) app.dotsize], 'Color',...
                            app.labelcolor(round((i-1)*255/(nlabels-1))+1, :), 'Opacity', app.alphavalue);
                    else
                        im = insertShape(im, 'FilledCircle', [num_track(frameID, i*3-1) num_track(frameID, i*3) app.dotsize], 'Color',...
                            app.labelcolor(round((i-1)*255/(nlabels-1))+1, :)/2^8, 'Opacity', app.alphavalue);
                    end
                end
            end
        end
        
        function generate_content(app, readerobj, num_track, UIAxes, csvdata, camID)
            im = readFrame(readerobj);
            brightness = app.BrightnessEditField.Value;
            if brightness ~= 1
                im = rgb2hsv(im);
                intensity = im(:, :, 3);
                intensity = mat2gray(intensity, [0 brightness]);
                im(:, :, 3) = intensity;
                im = hsv2rgb(im);
            end
            [im, frameID] = draw_labels(app, num_track, im, readerobj);
            switch camID
                case 1
                    app.him1 = image(im, 'Parent', UIAxes);
                case 2
                    app.him2 = image(im, 'Parent', UIAxes);
                case 3
                    app.him3 = image(im, 'Parent', UIAxes);
            end
            app.hrect1 = [];
            app.hrect2 = [];
            app.hrect3 = [];
            generate_square(app, csvdata, UIAxes, frameID, camID);
        end
        
        function update_content(app, readerobj, num_track, UIAxes, csvdata, camID)
            im = readFrame(readerobj);
            brightness = app.BrightnessEditField.Value;
            if brightness ~= 1
                im = rgb2hsv(im);
                intensity = im(:, :, 3);
                intensity = mat2gray(intensity, [0 brightness]);
                im(:, :, 3) = intensity;
                im = hsv2rgb(im);
            end
            [im, frameID] = draw_labels(app, num_track, im, readerobj);
            switch camID
                case 1
                    app.him1.CData = im;
                case 2
                    app.him2.CData = im;
                case 3
                    app.him3.CData = im;
            end
            generate_square(app, csvdata, UIAxes, frameID, camID);
        end
        
        function generate_plot(app, minduration)
            persistent hlgd
            cla(app.UIAxes2);
            try
                delete(hlgd);
                hlgd = [];
            end
            lcolor = {'r', 'g', 'b', 'y', 'm', 'c', 'k'};
            if (app.AudioCheckBox.Value && ~isempty(app.audiodata)) && (app.FPCheckBox.Value && ~isempty(app.fpdata))
                yl = [inf -inf];
                for i = 1:numel(app.fpdata)
                    fpdata = app.fpdata{i};
                    fpdata(fpdata(:, 1) > minduration, :) = [];
                    hp(i) = plot(app.UIAxes2, fpdata(:, 1), fpdata(:, 2), '-', 'Color', lcolor{i});
                    yl(1) = min(yl(1), min(fpdata(:, 2)));
                    yl(2) = max(yl(2), max(fpdata(:, 2)));
                    lgd{i} = ['Channel ' num2str(i)];
                end
                plot(app.UIAxes2, [0 minduration], [0 0], '-k');
                app.hline = line(app.UIAxes2, [app.currenttime; app.currenttime], yl, 'Color', 'b', 'LineStyle', '-');
                app.UIAxes2.XLim = [0 minduration];
                app.UIAxes2.YLim = yl;
                
                plot(app.UIAxes2, app.audiodata.timestamps, rescale(app.audiodata.signal, yl(1), yl(2)), '-k');
                
                hlgd = legend(app.UIAxes2, hp, lgd);
                legend(app.UIAxes2, 'boxoff');
            else
                if app.AudioCheckBox.Value && ~isempty(app.audiodata)
                    if app.OptSessionCheckBox.Value
                        if ~isempty(app.audiodata.laser_starttime)
                            for i = 1:numel(app.audiodata.laser_starttime)
                                patch(app.UIAxes2, [app.audiodata.laser_starttime(i) app.audiodata.laser_starttime(i) app.audiodata.laser_endtime(i) app.audiodata.laser_endtime(i)],...
                                    [min(app.audiodata.signal) max(app.audiodata.signal) max(app.audiodata.signal) min(app.audiodata.signal)],...
                                    [0 1 0], 'FaceAlpha', 1, 'EdgeColor', 'none');
                            end
                        else
                            line(app.UIAxes2, [app.standard_delay; app.standard_delay], [min(app.audiodata.signal); max(app.audiodata.signal)],...
                                'Color', 'g', 'LineStyle', '--');
                        end
                    end
                    plot(app.UIAxes2, app.audiodata.timestamps, app.audiodata.signal, '-k');
                    app.hline = line(app.UIAxes2, [app.currenttime; app.currenttime], [min(app.audiodata.signal); max(app.audiodata.signal)],...
                        'Color', 'r', 'LineStyle', '-');
                    app.UIAxes2.XLim = [0 minduration];
                    app.UIAxes2.YLim = [min(app.audiodata.signal) max(app.audiodata.signal)];
                end
                if app.FPCheckBox.Value && ~isempty(app.fpdata)
                    yl = [inf -inf];
                    for i = 1:numel(app.fpdata)
                        fpdata = app.fpdata{i};
                        fpdata(fpdata(:, 1) > minduration, :) = [];
                        plot(app.UIAxes2, fpdata(:, 1), fpdata(:, 2), '-', 'Color', lcolor{i});
                        yl(1) = min(yl(1), min(fpdata(:, 2)));
                        yl(2) = max(yl(2), max(fpdata(:, 2)));
                        lgd{i} = ['Channel ' num2str(i)];
                    end
                    plot(app.UIAxes2, [0 minduration], [0 0], '-k');
                    app.hline = line(app.UIAxes2, [app.currenttime; app.currenttime], yl, 'Color', 'k', 'LineStyle', '-');
                    app.UIAxes2.XLim = [0 minduration];
                    app.UIAxes2.YLim = yl;
                    hlgd = legend(app.UIAxes2, lgd);
                    legend(app.UIAxes2, 'boxoff');
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes, 'image');
            app.UIAxes.Visible = 'off';
            axis(app.UIAxes_2, 'image');
            app.UIAxes_2.Visible = 'off';
            axis(app.UIAxes_3, 'image');
            app.UIAxes_3.Visible = 'off';
            app.FrameRate = app.FrameRateEditField.Value;
            
            app.UITable.RowName = {'EyeL', 'EyeR', 'NoseTip', 'Tongue', 'ThumbL', 'IndexL', 'MiddleL', 'RingL', 'LittleL', 'PawL', 'ThumbR',...
                'IndexR', 'MiddleR', 'RingR', 'LittleR', 'PawR', 'AnkleL', 'AnkleR', 'Tail', 'Pellet', 'AHTop', 'AHBottom', 'CubeCenter',...
                'ATop', 'ABottom', 'BLeft', 'BRight', 'CLeft', 'CRight', 'DLeft', 'DRight', 'Jaw', 'AHCenter'};
            app.UITable.ColumnName = {'pcutoff', 'showup'};
            pcutoff = ones(numel(app.UITable.RowName), 1)*0.75;
            showup = [true, true, true, false, false, false, false, false, false, true, false,...
                false, false, false, false, true, true, true, true, false, true, true, false,...
                false, false, false, false, false, false, false, false, true, true]';
            app.UITable.Data = table(pcutoff, showup);
            
            app.DropDown.Items = {'EyeL', 'EyeR', 'NoseTip', 'Tongue', 'ThumbL', 'IndexL', 'MiddleL', 'RingL', 'LittleL', 'PawL', 'ThumbR',...
                'IndexR', 'MiddleR', 'RingR', 'LittleR', 'PawR', 'AnkleL', 'AnkleR', 'Tail', 'Pellet', 'AHTop', 'AHBottom', 'CubeCenter',...
                'ATop', 'ABottom', 'BLeft', 'BRight', 'CLeft', 'CRight', 'DLeft', 'DRight', 'Jaw', 'AHCenter'};
            app.DropDown.ItemsData = 1:numel(app.DropDown.Items);
            app.DropDown.Value = 10;
            
            app.DropDown_2.Items = {'EyeL', 'EyeR', 'NoseTip', 'Tongue', 'ThumbL', 'IndexL', 'MiddleL', 'RingL', 'LittleL', 'PawL', 'ThumbR',...
                'IndexR', 'MiddleR', 'RingR', 'LittleR', 'PawR', 'AnkleL', 'AnkleR', 'Tail', 'Pellet', 'AHTop', 'AHBottom', 'CubeCenter',...
                'ATop', 'ABottom', 'BLeft', 'BRight', 'CLeft', 'CRight', 'DLeft', 'DRight', 'Jaw', 'AHCenter'};
            app.DropDown_2.ItemsData = 1:numel(app.DropDown_2.Items);
            app.DropDown_2.Value = 3;
            
            app.DropDown_3.Items = {'EyeL', 'EyeR', 'NoseTip', 'Tongue', 'ThumbL', 'IndexL', 'MiddleL', 'RingL', 'LittleL', 'PawL', 'ThumbR',...
                'IndexR', 'MiddleR', 'RingR', 'LittleR', 'PawR', 'AnkleL', 'AnkleR', 'Tail', 'Pellet', 'AHTop', 'AHBottom', 'CubeCenter',...
                'ATop', 'ABottom', 'BLeft', 'BRight', 'CLeft', 'CRight', 'DLeft', 'DRight', 'Jaw', 'AHCenter'};
            app.DropDown_3.ItemsData = 1:numel(app.DropDown_3.Items);
            app.DropDown_3.Value = 10;
        end

        % Button pushed function: ExpFolderButton
        function ExpFolderButtonPushed(app, event)
            app.TrialsListBox.Enable = 1;
            app.NextFrameButton.Enable = 1;
            app.LastFrameButton.Enable = 1;
            app.OmissionCheckBox.Enable = 0;
            curpwd = pwd;
            Path_change = 0;
            try
                Exp_Path = uigetdir(app.Exp_Path, 'Pick a folder');
            catch
                Exp_Path = uigetdir('', 'Pick a folder');
            end
            if Exp_Path ~= 0
                app.Exp_Path = Exp_Path;
                Path_change = 1;
            end
            if Path_change
                app.OptSessionCheckBox.Value = 0;
                app.VideoPanel.Title = 'Video';
                app.EditField.Value = app.Exp_Path;
                app.TrialsListBox.Items = cell(0);
                app.TrialsListBox.ItemsData = [];
                app.ListBox.Items = cell(0);
                app.ListBox.ItemsData = [];
                cd(app.Exp_Path);
                trial = 0;
                DirList = dir;
                for i = 3:size(DirList)
                    if numel(DirList(i).name) > 9
                        if strcmp(DirList(i).name(1:9), 'PG1camera')
                            temp = str2double(DirList(i).name(10:end-4));
                            if temp > trial
                                trial = temp;
                            end
                        end
                    end
                end
                app.TrialsListBox.Items = string(num2cell((1:trial)'));
                app.TrialsListBox.ItemsData = 1:trial;
                
                for i = 1:trial
                    csvdata = load([app.Exp_Path '\PG1gpio' num2str(i) '.csv']);
                    if sum(csvdata(:, 1)) > 12
                        app.TrialsListBox.Items{i} = [app.TrialsListBox.Items{i} '_I'];
                    else
                        app.TrialsListBox.Items{i} = [app.TrialsListBox.Items{i} '_NI'];
                    end
                end
                try
                    temp = load('Analysis_Session.mat');
                    app.Video_annotation = temp.Video_annotation;
                    if ~isfield(app.Video_annotation, 'Omission')
                        [app.Video_annotation(:).Omission] = deal(0);
                        Video_annotation = app.Video_annotation;
                        save('Analysis_Session.mat', 'Video_annotation');
                    end
                    nDisgard = 0;
                    for i = 1:numel(app.Video_annotation)
                        nDisgard = nDisgard+app.Video_annotation(i).Disgard;
                        if app.Video_annotation(i).Disgard
                            app.TrialsListBox.Items{i} = [app.TrialsListBox.Items{i} '_Disgard'];
                        end
                    end
                    app.Label.Text = num2str(nDisgard);
                catch
                    app.Video_annotation = struct('Disgard', num2cell(zeros(trial, 1)), 'time_ready2bite', cell(trial, 1),...
                        'time_feeding_end', cell(trial, 1), 'Omission', num2cell(zeros(trial, 1)));
                    Video_annotation = app.Video_annotation;
                    save('Analysis_Session.mat', 'Video_annotation');
                    app.Label.Text = '0';
                end
            end
            cd(curpwd);
            figure(app.FreemovingFeedingAnalysisUIFigure);
        end

        % Value changed function: TrialsListBox
        function TrialsListBoxValueChanged(app, event)
            value = app.TrialsListBox.Value;
            app.TrialsListBox.Enable = 0;
            app.OmissionCheckBox.Enable = 0;
            
            app.MouthOpenButton.Enable = 0;
            app.TongueOutButton.Enable = 0;
            app.FoodinMouthButton.Enable = 0;
            app.PawLReachButton.Enable = 0;
            app.PawRReachButton.Enable = 0;
            app.SitButton.Enable = 0;
            app.PawLAdjustmentButton.Enable = 0;
            app.PawRAdjustmentButton.Enable = 0;
            app.BiteBoutButton.Enable = 0;
            app.FeedingEndButton.Enable = 0;
            app.ToNoseButton.Enable = 0;
            app.RetrievalStartButton.Enable = 0;
            app.MouthRetrievalButton.Enable = 0;
            
            app.Spinner.Value = 1;
            if numel(value) == 1
                try
                    app.readerobj1 = VideoReader([app.Exp_Path '\PG1camera' num2str(value) '.avi']);
                catch
                    app.readerobj1 = VideoReader([app.Exp_Path '\PG1camera' num2str(value) '.mp4']);
                end
                app.csvdata1 = load([app.Exp_Path '\PG1gpio' num2str(value) '.csv']);
                try
                    app.readerobj2 = VideoReader([app.Exp_Path '\PG2camera' num2str(value) '.avi']);
                catch
                    app.readerobj2 = VideoReader([app.Exp_Path '\PG2camera' num2str(value) '.mp4']);
                end
                app.csvdata2 = load([app.Exp_Path '\PG2gpio' num2str(value) '.csv']);
                video3location = strsplit(app.Exp_Path, filesep);
                video3location{end-2} = [video3location{end-2} ' Camera3'];
                video3location{end-1} = [video3location{end-1} ' PG3'];
%                 video3location{end-1} = [video3location{end-1} ' Camera3']; % for old data
%                 video3location{end} = [video3location{end} ' PG3']; % for old data
                video3location = strjoin(video3location, filesep);
                try
                    app.readerobj3 = VideoReader([video3location '\PG3camera' num2str(value) '.avi']);
                catch
                    app.readerobj3 = VideoReader([video3location '\PG3camera' num2str(value) '.mp4']);
                end
                app.csvdata3 = load([video3location '\PG3gpio' num2str(value) '.csv']);
                
                if app.TrackingDataCheckBox.Value
                    cam1track = find_tracking_filename(app.Exp_Path, ['PG1camera' num2str(value)]);
                    cam2track = find_tracking_filename(app.Exp_Path, ['PG2camera' num2str(value)]);
                    cam3track = find_tracking_filename(video3location, ['PG3camera' num2str(value)]);
                    filename = cell(1, 3);
                    filename{1} = cam1track;
                    filename{2} = cam2track;
                    filename{3} = cam3track;
                    num_track = cell(1, 3);
                    parfor i = 1:3
                        if ~isempty(filename{i})
                            [num_track{i}, ~, ~] = xlsread(filename{i});
                        end
                    end
                    app.num_track1 = num_track{1};
                    app.num_track2 = num_track{2};
                    app.num_track3 = num_track{3};
                end
                
                cla(app.UIAxes);
                cla(app.UIAxes_2);
                cla(app.UIAxes_3);
                app.hrect1 = [];
                app.hrect2 = [];
                app.hrect3 = [];
                app.currenttime = inf;
                if app.Video1CheckBox.Value
                    generate_content(app, app.readerobj1, app.num_track1, app.UIAxes, app.csvdata1, 1);
                    if strcmp(app.readerobj1.Name(end-2:end), 'avi')
                        app.currenttime = min(app.currenttime, app.readerobj1.CurrentTime);
                    elseif strcmp(app.readerobj1.Name(end-2:end), 'mp4')
                        app.currenttime = min(app.currenttime, app.readerobj1.CurrentTime-1/app.readerobj1.FrameRate);
                    end
                end
                if app.Video2CheckBox.Value
                    generate_content(app, app.readerobj2, app.num_track2, app.UIAxes_2, app.csvdata2, 2);
                    if strcmp(app.readerobj2.Name(end-2:end), 'avi')
                        app.currenttime = min(app.currenttime, app.readerobj2.CurrentTime);
                    elseif strcmp(app.readerobj2.Name(end-2:end), 'mp4')
                        app.currenttime = min(app.currenttime, app.readerobj2.CurrentTime-1/app.readerobj2.FrameRate);
                    end    
                end
                if app.Video3CheckBox.Value
                    generate_content(app, app.readerobj3, app.num_track3, app.UIAxes_3, app.csvdata3, 3);
                    if strcmp(app.readerobj3.Name(end-2:end), 'avi')
                        app.currenttime = min(app.currenttime, app.readerobj3.CurrentTime);
                    elseif strcmp(app.readerobj3.Name(end-2:end), 'mp4')
                        app.currenttime = min(app.currenttime, app.readerobj3.CurrentTime-1/app.readerobj3.FrameRate);
                    end  
                end
                app.maxinterval = 1/min([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
                minduration = min([app.readerobj1.Duration app.readerobj2.Duration app.readerobj3.Duration])-4.5;
                
                audiolocation = app.Exp_Path(1:end-7);
                try
                    temp = load([audiolocation '\Trial' num2str(value, '%03d') '.mat']);
                    app.audiodata = temp.audiodata;
                    [b, a] = butter(6, app.lowpassfreq/(app.SampleRate/2), 'low');
                    app.audiodata.signal = filtfilt(b, a, app.audiodata.signal);
                    app.audiodata.signalsc = rescale(app.audiodata.signal, -1, 1);
                catch
                    app.audiodata = [];
                end
                
                try
                    temp = load([audiolocation '\FPData.mat']);
                    app.fpdata = temp.zsignal_all(:, value);
                catch
                    app.fpdata = [];
                end
                generate_plot(app, minduration);
                
                app.Slider.Limits = [app.maxinterval minduration];
                app.Slider.Value = app.currenttime;
                app.GotoEditField.Limits = app.Slider.Limits;
                app.GotoEditField.Value = app.Slider.Value;
                if app.toEditField.Value > minduration
                    app.toEditField.Value = minduration;
                end
                
                if ~app.Video_annotation(value).Disgard
                    app.MarktrialtodisgardButton.Enable = 1;
                    app.MarktrialtoincludeButton.Enable = 0;
                    app.MarkasreadytobiteButton.Enable = 1;
                    app.MarkasfeedingendButton.Enable = 1;
                else
                    app.MarktrialtodisgardButton.Enable = 0;
                    app.MarktrialtoincludeButton.Enable = 1;
                    app.MarkasreadytobiteButton.Enable = 0;
                    app.MarkasfeedingendButton.Enable = 0;
                end
                
                app.OmissionCheckBox.Enable = 1;
                app.OmissionCheckBox.Value = app.Video_annotation(value).Omission;
                
                try
                    temp = load([app.Exp_Path '\LabelledEvents' num2str(value) '.mat']);
                    LabelledEvents = temp.LabelledEvents;
                    app.LabelledEvents = LabelledEvents;
                    if isfield(LabelledEvents, 'ToMouth')
                        LabelledEvents.ToNose = LabelledEvents.ToMouth;
                        LabelledEvents = rmfield(LabelledEvents, 'ToMouth');
                        app.LabelledEvents = LabelledEvents;
                        update_LabelledEvents(app);
                    end
                catch
                    app.LabelledEvents = struct('MouthOpen', [], 'MouthClosed', [], 'TongueOut', [], 'TongueIn', [], 'FoodinMouth', [],...
                        'PawLReachStart', [], 'PawLReachEnd', [], 'PawRReachStart', [], 'PawRReachEnd', [],...
                        'SitStart', [], 'SitEnd', [], 'PawLAdjustmentStart', [], 'PawLAdjustmentEnd', [],...
                        'PawRAdjustmentStart', [], 'PawRAdjustmentEnd', [], 'BiteBoutStart', [], 'BiteBoutEnd', [], 'FeedingEnd', [], 'ToNose', [],...
                        'RetrievalStart', [], 'MouthRetrievalStart', [], 'MouthRetrievalEnd', []);
                    LabelledEvents = app.LabelledEvents;
                    save([app.Exp_Path '\LabelledEvents' num2str(value) '.mat'], 'LabelledEvents');
                end
                assignin('base', 'LabelledEvents', LabelledEvents);
                disp(LabelledEvents);
                app.MouthOpenButton.Enable = 1;
                app.TongueOutButton.Enable = 1;
                app.FoodinMouthButton.Enable = 1;
                app.PawLReachButton.Enable = 1;
                app.PawRReachButton.Enable = 1;
                app.SitButton.Enable = 1;
                app.PawLAdjustmentButton.Enable = 1;
                app.PawRAdjustmentButton.Enable = 1;
                app.BiteBoutButton.Enable = 1;
                app.FeedingEndButton.Enable = 1;
                app.ToNoseButton.Enable = 1;
                app.RetrievalStartButton.Enable = 1;
                app.MouthRetrievalButton.Enable = 1;
            end
            app.TrialsListBox.Enable = 1;
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value
                app.Lamp.Color = [0 1 0];
                if app.Video1CheckBox.Value
                    if strcmp(app.readerobj1.Name(end-2:end), 'avi')
                        app.readerobj1.CurrentTime = app.currenttime;
                    elseif strcmp(app.readerobj1.Name(end-2:end), 'mp4')
                        app.readerobj1.CurrentTime = app.currenttime+1/app.readerobj1.FrameRate;
                    end
                end
                if app.Video2CheckBox.Value
                    if strcmp(app.readerobj2.Name(end-2:end), 'avi')
                        app.readerobj2.CurrentTime = app.currenttime;
                    elseif strcmp(app.readerobj2.Name(end-2:end), 'mp4')
                        app.readerobj2.CurrentTime = app.currenttime+1/app.readerobj2.FrameRate;
                    end
                end
                if app.Video3CheckBox.Value
                    if strcmp(app.readerobj3.Name(end-2:end), 'avi')
                        app.readerobj3.CurrentTime = app.currenttime;
                    elseif strcmp(app.readerobj3.Name(end-2:end), 'mp4')
                        app.readerobj3.CurrentTime = app.currenttime+1/app.readerobj3.FrameRate;
                    end
                end
                interval = 1/app.FrameRate;
            end
            while app.PlayButton.Value && app.currenttime <= app.Slider.Limits(2)-app.maxinterval
                time0 = tic;
                app.currenttime = inf;
                if app.Video1CheckBox.Value
                    update_content(app, app.readerobj1, app.num_track1, app.UIAxes, app.csvdata1, 1);
                    if strcmp(app.readerobj1.Name(end-2:end), 'avi')
                        app.currenttime = min(app.currenttime, app.readerobj1.CurrentTime);
                    elseif strcmp(app.readerobj1.Name(end-2:end), 'mp4')
                        app.currenttime = min(app.currenttime, app.readerobj1.CurrentTime-1/app.readerobj1.FrameRate);
                    end
                end
                if app.Video2CheckBox.Value
                    update_content(app, app.readerobj2, app.num_track2, app.UIAxes_2, app.csvdata2, 2);
                    if strcmp(app.readerobj2.Name(end-2:end), 'avi')
                        app.currenttime = min(app.currenttime, app.readerobj2.CurrentTime);
                    elseif strcmp(app.readerobj2.Name(end-2:end), 'mp4')
                        app.currenttime = min(app.currenttime, app.readerobj2.CurrentTime-1/app.readerobj2.FrameRate);
                    end
                end
                if app.Video3CheckBox.Value
                    update_content(app, app.readerobj3, app.num_track3, app.UIAxes_3, app.csvdata3, 3);
                    if strcmp(app.readerobj3.Name(end-2:end), 'avi')
                        app.currenttime = min(app.currenttime, app.readerobj3.CurrentTime);
                    elseif strcmp(app.readerobj3.Name(end-2:end), 'mp4')
                        app.currenttime = min(app.currenttime, app.readerobj3.CurrentTime-1/app.readerobj3.FrameRate);
                    end
                end
                
                if (app.AudioCheckBox.Value && ~isempty(app.audiodata)) || (app.FPCheckBox.Value && ~isempty(app.fpdata))
                    app.hline.XData = [app.currenttime; app.currenttime];
                end
                if app.SoundCheckBox.Value && ~isempty(app.audiodata)
                    audiosegment = app.audiodata.signalsc(app.audiodata.timestamps >= app.currenttime-app.maxinterval &...
                        app.audiodata.timestamps < app.currenttime);
                    sound(audiosegment, app.SampleRate);
                end
                
                app.Slider.Value = app.currenttime;
                app.GotoEditField.Value = app.Slider.Value;
                drawnow limitrate;
                while toc(time0) < interval
                end
            end
            app.Lamp.Color = [1 1 1];
            app.PlayButton.Value = 0;
        end

        % Value changed function: FrameRateEditField
        function FrameRateEditFieldValueChanged(app, event)
            value = app.FrameRateEditField.Value;
            if value <= 0
                app.FrameRateEditField.Value = app.FrameRate;
            else
                app.FrameRate = value;
            end
        end

        % Value changed function: Video1CheckBox
        function Video1CheckBoxValueChanged(app, event)
            value = app.Video1CheckBox.Value;
            if ~value
                if ~app.Video2CheckBox.Value && ~app.Video3CheckBox.Value
                    app.Video1CheckBox.Value = 1;
                else
                    cla(app.UIAxes);
                    try
                        delete(app.hrect1);
                    end
                    app.hrect1 = [];
                end
            else
                if ~isempty(app.TrialsListBox.Value)
                    if strcmp(app.readerobj1.Name(end-2:end), 'avi')
                        app.readerobj1.CurrentTime = app.currenttime-1/app.readerobj1.FrameRate;
                    elseif strcmp(app.readerobj1.Name(end-2:end), 'mp4')
                        app.readerobj1.CurrentTime = app.currenttime;
                    end
                    generate_content(app, app.readerobj1, app.num_track1, app.UIAxes, app.csvdata1, 1);
                end
            end
        end

        % Value changed function: Video2CheckBox
        function Video2CheckBoxValueChanged(app, event)
            value = app.Video2CheckBox.Value;
            if ~value
                if ~app.Video1CheckBox.Value && ~app.Video3CheckBox.Value
                    app.Video2CheckBox.Value = 1;
                else
                    cla(app.UIAxes_2);
                    try
                        delete(app.hrect2);
                    end
                    app.hrect2 = [];
                end
            else
                if ~isempty(app.TrialsListBox.Value)
                    if strcmp(app.readerobj2.Name(end-2:end), 'avi')
                        app.readerobj2.CurrentTime = app.currenttime-1/app.readerobj2.FrameRate;
                    elseif strcmp(app.readerobj2.Name(end-2:end), 'mp4')
                        app.readerobj2.CurrentTime = app.currenttime;
                    end
                    generate_content(app, app.readerobj2, app.num_track2, app.UIAxes_2, app.csvdata2, 2);
                end
            end
        end

        % Value changed function: Video3CheckBox
        function Video3CheckBoxValueChanged(app, event)
            value = app.Video3CheckBox.Value;
            if ~value
                if ~app.Video1CheckBox.Value && ~app.Video2CheckBox.Value
                    app.Video3CheckBox.Value = 1;
                else
                    cla(app.UIAxes_3);
                    try
                        delete(app.hrect3);
                    end
                    app.hrect3 = [];
                end
            else
                if ~isempty(app.TrialsListBox.Value)
                    if strcmp(app.readerobj3.Name(end-2:end), 'avi')
                        app.readerobj3.CurrentTime = app.currenttime-1/app.readerobj3.FrameRate;
                    elseif strcmp(app.readerobj3.Name(end-2:end), 'mp4')
                        app.readerobj3.CurrentTime = app.currenttime;
                    end
                    generate_content(app, app.readerobj3, app.num_track3, app.UIAxes_3, app.csvdata3, 3);
                end
            end
        end

        % Value changed function: PlayBackButton
        function PlayBackButtonValueChanged(app, event)
            value = app.PlayBackButton.Value;
            if value
                app.Lamp.Color = [0 1 0];
                interval = 1/app.FrameRate;
                jumptime = 2*app.maxinterval;
            end
            while app.PlayBackButton.Value && app.currenttime-jumptime >= 0
                time0 = tic;
                currenttime = 0;
                if app.Video1CheckBox.Value
                    if strcmp(app.readerobj1.Name(end-2:end), 'avi')
                        app.readerobj1.CurrentTime = app.currenttime-2/app.readerobj1.FrameRate;
                    elseif strcmp(app.readerobj1.Name(end-2:end), 'mp4')
                        app.readerobj1.CurrentTime = app.currenttime-1/app.readerobj1.FrameRate;
                    end
                    update_content(app, app.readerobj1, app.num_track1, app.UIAxes, app.csvdata1, 1);
                    if strcmp(app.readerobj1.Name(end-2:end), 'avi')
                        currenttime = max(currenttime, app.readerobj1.CurrentTime);
                    elseif strcmp(app.readerobj1.Name(end-2:end), 'mp4')
                        currenttime = max(currenttime, app.readerobj1.CurrentTime-1/app.readerobj1.FrameRate);
                    end
                end
                if app.Video2CheckBox.Value
                    if strcmp(app.readerobj2.Name(end-2:end), 'avi')
                        app.readerobj2.CurrentTime = app.currenttime-2/app.readerobj2.FrameRate;
                    elseif strcmp(app.readerobj2.Name(end-2:end), 'mp4')
                        app.readerobj2.CurrentTime = app.currenttime-1/app.readerobj2.FrameRate;
                    end
                    update_content(app, app.readerobj2, app.num_track2, app.UIAxes_2, app.csvdata2, 2);
                    if strcmp(app.readerobj2.Name(end-2:end), 'avi')
                        currenttime = max(currenttime, app.readerobj2.CurrentTime);
                    elseif strcmp(app.readerobj2.Name(end-2:end), 'mp4')
                        currenttime = max(currenttime, app.readerobj2.CurrentTime-1/app.readerobj2.FrameRate);
                    end
                end
                if app.Video3CheckBox.Value
                    if strcmp(app.readerobj3.Name(end-2:end), 'avi')
                        app.readerobj3.CurrentTime = app.currenttime-2/app.readerobj3.FrameRate;
                    elseif strcmp(app.readerobj3.Name(end-2:end), 'mp4')
                        app.readerobj3.CurrentTime = app.currenttime-1/app.readerobj3.FrameRate;
                    end
                    update_content(app, app.readerobj3, app.num_track3, app.UIAxes_3, app.csvdata3, 3);
                    if strcmp(app.readerobj3.Name(end-2:end), 'avi')
                        currenttime = max(currenttime, app.readerobj3.CurrentTime);
                    elseif strcmp(app.readerobj3.Name(end-2:end), 'mp4')
                        currenttime = max(currenttime, app.readerobj3.CurrentTime-1/app.readerobj3.FrameRate);
                    end
                end
                app.currenttime = currenttime;
                
                if (app.AudioCheckBox.Value && ~isempty(app.audiodata)) || (app.FPCheckBox.Value && ~isempty(app.fpdata))
                    app.hline.XData = [app.currenttime; app.currenttime];
                end
                if app.SoundCheckBox.Value && ~isempty(app.audiodata)
                    audiosegment = app.audiodata.signalsc(app.audiodata.timestamps >= app.currenttime-app.maxinterval &...
                        app.audiodata.timestamps < app.currenttime);
                    sound(audiosegment(end:-1:1), app.SampleRate);
                end
                
                app.Slider.Value = app.currenttime;
                app.GotoEditField.Value = app.Slider.Value;
                drawnow limitrate;
                while toc(time0) < interval
                end
            end
            app.Lamp.Color = [1 1 1];
            app.PlayBackButton.Value = 0;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.currenttime = value;
            if app.Video1CheckBox.Value
                if strcmp(app.readerobj1.Name(end-2:end), 'avi')
                    app.readerobj1.CurrentTime = app.currenttime-1/app.readerobj1.FrameRate;
                elseif strcmp(app.readerobj1.Name(end-2:end), 'mp4')
                    app.readerobj1.CurrentTime = app.currenttime;
                end
                update_content(app, app.readerobj1, app.num_track1, app.UIAxes, app.csvdata1, 1);
            end
            if app.Video2CheckBox.Value
                if strcmp(app.readerobj2.Name(end-2:end), 'avi')
                    app.readerobj2.CurrentTime = app.currenttime-1/app.readerobj2.FrameRate;
                elseif strcmp(app.readerobj2.Name(end-2:end), 'mp4')
                    app.readerobj2.CurrentTime = app.currenttime;
                end
                update_content(app, app.readerobj2, app.num_track2, app.UIAxes_2, app.csvdata2, 2);
            end
            if app.Video3CheckBox.Value
                if strcmp(app.readerobj3.Name(end-2:end), 'avi')
                    app.readerobj3.CurrentTime = app.currenttime-1/app.readerobj3.FrameRate;
                elseif strcmp(app.readerobj3.Name(end-2:end), 'mp4')
                    app.readerobj3.CurrentTime = app.currenttime;
                end
                update_content(app, app.readerobj3, app.num_track3, app.UIAxes_3, app.csvdata3, 3);
            end
            if (app.AudioCheckBox.Value && ~isempty(app.audiodata)) || (app.FPCheckBox.Value && ~isempty(app.fpdata))
                app.hline.XData = [app.currenttime; app.currenttime];
            end
            app.GotoEditField.Value = app.Slider.Value;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.currenttime = changingValue;
            if app.Video1CheckBox.Value
                if strcmp(app.readerobj1.Name(end-2:end), 'avi')
                    app.readerobj1.CurrentTime = app.currenttime-1/app.readerobj1.FrameRate;
                elseif strcmp(app.readerobj1.Name(end-2:end), 'mp4')
                    app.readerobj1.CurrentTime = app.currenttime;
                end
                update_content(app, app.readerobj1, app.num_track1, app.UIAxes, app.csvdata1, 1);
            end
            if app.Video2CheckBox.Value
                if strcmp(app.readerobj2.Name(end-2:end), 'avi')
                    app.readerobj2.CurrentTime = app.currenttime-1/app.readerobj2.FrameRate;
                elseif strcmp(app.readerobj2.Name(end-2:end), 'mp4')
                    app.readerobj2.CurrentTime = app.currenttime;
                end
                update_content(app, app.readerobj2, app.num_track2, app.UIAxes_2, app.csvdata2, 2);
            end
            if app.Video3CheckBox.Value
                if strcmp(app.readerobj3.Name(end-2:end), 'avi')
                    app.readerobj3.CurrentTime = app.currenttime-1/app.readerobj3.FrameRate;
                elseif strcmp(app.readerobj3.Name(end-2:end), 'mp4')
                    app.readerobj3.CurrentTime = app.currenttime;
                end
                update_content(app, app.readerobj3, app.num_track3, app.UIAxes_3, app.csvdata3, 3);
            end
            if (app.AudioCheckBox.Value && ~isempty(app.audiodata)) || (app.FPCheckBox.Value && ~isempty(app.fpdata))
                app.hline.XData = [app.currenttime; app.currenttime];
            end
        end

        % Button pushed function: FirstFrameButton
        function FirstFrameButtonPushed(app, event)
            app.Slider.Value = app.Slider.Limits(1);
            SliderValueChanged(app, event);
            app.GotoEditField.Value = app.Slider.Value;
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            if app.currenttime <= app.Slider.Limits(2)-app.maxinterval
                app.NextFrameButton.Enable = 0;
                app.currenttime = inf;
                if app.Video1CheckBox.Value
                    update_content(app, app.readerobj1, app.num_track1, app.UIAxes, app.csvdata1, 1);
                    if strcmp(app.readerobj1.Name(end-2:end), 'avi')
                        app.currenttime = min(app.currenttime, app.readerobj1.CurrentTime);
                    elseif strcmp(app.readerobj1.Name(end-2:end), 'mp4')
                        app.currenttime = min(app.currenttime, app.readerobj1.CurrentTime-1/app.readerobj1.FrameRate);
                    end
                end
                if app.Video2CheckBox.Value
                    update_content(app, app.readerobj2, app.num_track2, app.UIAxes_2, app.csvdata2, 2);
                    if strcmp(app.readerobj2.Name(end-2:end), 'avi')
                        app.currenttime = min(app.currenttime, app.readerobj2.CurrentTime);
                    elseif strcmp(app.readerobj2.Name(end-2:end), 'mp4')
                        app.currenttime = min(app.currenttime, app.readerobj2.CurrentTime-1/app.readerobj2.FrameRate);
                    end
                end
                if app.Video3CheckBox.Value
                    update_content(app, app.readerobj3, app.num_track3, app.UIAxes_3, app.csvdata3, 3);
                    if strcmp(app.readerobj3.Name(end-2:end), 'avi')
                        app.currenttime = min(app.currenttime, app.readerobj3.CurrentTime);
                    elseif strcmp(app.readerobj3.Name(end-2:end), 'mp4')
                        app.currenttime = min(app.currenttime, app.readerobj3.CurrentTime-1/app.readerobj3.FrameRate);
                    end
                end
                if (app.AudioCheckBox.Value && ~isempty(app.audiodata)) || (app.FPCheckBox.Value && ~isempty(app.fpdata))
                    app.hline.XData = [app.currenttime; app.currenttime];
                end
                if app.SoundCheckBox.Value && ~isempty(app.audiodata)
                    audiosegment = app.audiodata.signalsc(app.audiodata.timestamps >= app.currenttime-app.maxinterval &...
                        app.audiodata.timestamps < app.currenttime);
                    sound(audiosegment, app.SampleRate);
                end
                app.Slider.Value = app.currenttime;
                app.GotoEditField.Value = app.Slider.Value;
                app.NextFrameButton.Enable = 1;
            end
        end

        % Button pushed function: LastFrameButton
        function LastFrameButtonPushed(app, event)
            jumptime = 2*app.maxinterval;
            if app.currenttime-jumptime >= 0
                app.LastFrameButton.Enable = 0;
                currenttime = 0;
                if app.Video1CheckBox.Value
                    if strcmp(app.readerobj1.Name(end-2:end), 'avi')
                        app.readerobj1.CurrentTime = app.currenttime-2/app.readerobj1.FrameRate;
                    elseif strcmp(app.readerobj1.Name(end-2:end), 'mp4')
                        app.readerobj1.CurrentTime = app.currenttime-1/app.readerobj1.FrameRate;
                    end
                    update_content(app, app.readerobj1, app.num_track1, app.UIAxes, app.csvdata1, 1);
                    if strcmp(app.readerobj1.Name(end-2:end), 'avi')
                        currenttime = max(currenttime, app.readerobj1.CurrentTime);
                    elseif strcmp(app.readerobj1.Name(end-2:end), 'mp4')
                        currenttime = max(currenttime, app.readerobj1.CurrentTime-1/app.readerobj1.FrameRate);
                    end
                end
                if app.Video2CheckBox.Value
                    if strcmp(app.readerobj2.Name(end-2:end), 'avi')
                        app.readerobj2.CurrentTime = app.currenttime-2/app.readerobj2.FrameRate;
                    elseif strcmp(app.readerobj2.Name(end-2:end), 'mp4')
                        app.readerobj2.CurrentTime = app.currenttime-1/app.readerobj2.FrameRate;
                    end
                    update_content(app, app.readerobj2, app.num_track2, app.UIAxes_2, app.csvdata2, 2);
                    if strcmp(app.readerobj2.Name(end-2:end), 'avi')
                        currenttime = max(currenttime, app.readerobj2.CurrentTime);
                    elseif strcmp(app.readerobj2.Name(end-2:end), 'mp4')
                        currenttime = max(currenttime, app.readerobj2.CurrentTime-1/app.readerobj2.FrameRate);
                    end
                end
                if app.Video3CheckBox.Value
                    if strcmp(app.readerobj3.Name(end-2:end), 'avi')
                        app.readerobj3.CurrentTime = app.currenttime-2/app.readerobj3.FrameRate;
                    elseif strcmp(app.readerobj3.Name(end-2:end), 'mp4')
                        app.readerobj3.CurrentTime = app.currenttime-1/app.readerobj3.FrameRate;
                    end
                    update_content(app, app.readerobj3, app.num_track3, app.UIAxes_3, app.csvdata3, 3);
                    if strcmp(app.readerobj3.Name(end-2:end), 'avi')
                        currenttime = max(currenttime, app.readerobj3.CurrentTime);
                    elseif strcmp(app.readerobj3.Name(end-2:end), 'mp4')
                        currenttime = max(currenttime, app.readerobj3.CurrentTime-1/app.readerobj3.FrameRate);
                    end
                end
                app.currenttime = currenttime;
                if (app.AudioCheckBox.Value && ~isempty(app.audiodata)) || (app.FPCheckBox.Value && ~isempty(app.fpdata))
                    app.hline.XData = [app.currenttime; app.currenttime];
                end
                if app.SoundCheckBox.Value && ~isempty(app.audiodata)
                    audiosegment = app.audiodata.signalsc(app.audiodata.timestamps >= app.currenttime-app.maxinterval &...
                        app.audiodata.timestamps < app.currenttime);
                    sound(audiosegment(end:-1:1), app.SampleRate);
                end
                app.Slider.Value = app.currenttime;
                app.GotoEditField.Value = app.Slider.Value;
                app.LastFrameButton.Enable = 1;
            end
        end

        % Value changed function: AudioCheckBox
        function AudioCheckBoxValueChanged(app, event)
            generate_plot(app, app.Slider.Limits(2));
        end

        % Button pushed function: WriteSoundTrackButton
        function WriteSoundTrackButtonPushed(app, event)
            timestart = app.timerangesEditField.Value;
            timestop = app.toEditField.Value;
            duration = [timestart timestop];
            if isempty(app.audiodata)
                return;
            end
            signal_filtered = app.audiodata.signalsc;
            
            value = [app.Video1CheckBox.Value app.Video2CheckBox.Value app.Video3CheckBox.Value];
            csvdata = cell(1, 3);
            readerobj = cell(1, 3);
            num_track = cell(1, 3);
            for i = 1:3
                csvdata{i} = eval(['app.csvdata' num2str(i)]);
                readerobj{i} = eval(['app.readerobj' num2str(i)]);
                num_track{i} = eval(['app.num_track' num2str(i)]);
            end
            label_table = table2array(app.UITable.Data);
            for i = 1:3
                if value(i)
                    writesound2video(app, app.FrameRate, app.SampleRate, app.audiodata, csvdata{i}, readerobj{i}, num_track{i}, signal_filtered, duration,...
                        label_table, app.label_trace, app.sqsize, app.dotsize, app.labelcolor, app.alphavalue, app.fpdata);
                end
            end
%             writesound2video2(app, app.FrameRate, app.SampleRate, app.audiodata, csvdata, readerobj, num_track, signal_filtered, duration,...
%                 label_table, app.label_trace, app.sqsize, app.dotsize, app.labelcolor, app.alphavalue, app.fpdata);
            msgbox('Done !');
        end

        % Button pushed function: PlotAudioDataButton
        function PlotAudioDataButtonPushed(app, event)
            audiolocation = app.Exp_Path(1:end-7);
            value = app.TrialsListBox.Value;
            workbar(0, 'Plotting Ongoing...', 'Progress');
            if numel(value > 1)
                for i = 1:numel(value)
                    try
                        temp = load([audiolocation '\Trial' num2str(value(i), '%03d')]);
                        [b, a] = butter(6, app.lowpassfreq/(app.SampleRate/2), 'low');
                        temp.audiodata.signal = filtfilt(b, a, temp.audiodata.signal);
                        if ~exist('hf', 'var')
                            hf = figure('Color', [1 1 1]);
                        end
                    catch
                        temp.audiodata = [];
                    end
                    if ~isempty(temp.audiodata)
                        haxis = subplot(8, 6, value(i), 'Parent', hf);
                        if isfield(temp.audiodata, 'laser_starttime')
                            if ~isempty(temp.audiodata.laser_starttime)
                                patch(haxis, [temp.audiodata.laser_starttime temp.audiodata.laser_starttime temp.audiodata.laser_endtime temp.audiodata.laser_endtime],...
                                    [min(temp.audiodata.signal) max(temp.audiodata.signal) max(temp.audiodata.signal) min(temp.audiodata.signal)],...
                                    [0 1 0], 'FaceAlpha', 1, 'EdgeColor', 'none');
                            else
                                line(haxis, [app.standard_delay; app.standard_delay], [min(temp.audiodata.signal); max(temp.audiodata.signal)],...
                                    'Color', 'g', 'LineStyle', '--', 'LineWidth', 1);
                            end
                        end
                        hold(haxis, 'on');
                        plot(haxis, temp.audiodata.timestamps, temp.audiodata.signal, '-k');
                        set(haxis, 'xLim', [0 temp.audiodata.timestamps(end)], 'yLim', [min(temp.audiodata.signal) max(temp.audiodata.signal)],...
                            'ButtonDownFcn', @extract_figure);
                        xlabel(haxis, 'Time (s)');
                        ylabel(haxis, 'Voltage (V)');
                        box(haxis, 'off');
                        title(haxis, ['Trial ' num2str(value(i))]);
                    end
                    workbar(i/numel(value), [num2str(i) '/' num2str(numel(value))], 'Progress');
                end
            end
            
            function extract_figure(haxis, ~)
                hfnew = figure('Color', [1 1 1]);
                copyobj(haxis, hfnew);
                set(gca, 'Position', [0.15 0.13 0.7 0.8]);
            end
        end

        % Button pushed function: MarktrialtodisgardButton
        function MarktrialtodisgardButtonPushed(app, event)
            trial = app.TrialsListBox.Value;
            if numel(trial) == 1
                app.Video_annotation(trial).Disgard = 1;
                update_annotations(app);
                app.MarktrialtodisgardButton.Enable = 0;
                app.MarktrialtoincludeButton.Enable = 1;
                app.Label.Text = num2str(str2double(app.Label.Text)+1);
                app.MarkasreadytobiteButton.Enable = 0;
                app.MarkasfeedingendButton.Enable = 0;
                app.TrialsListBox.Items{trial} = [app.TrialsListBox.Items{trial} '_Disgard'];
                try
                    audiolocation = app.Exp_Path(1:end-7);
                    temp = load([audiolocation '\Detected_Bite_Events.mat']);
                    Audio_analysis = temp.Audio_analysis;
                    Audio_analysis(trial).time_bites = [];
                    Audio_analysis(trial).amplitude_bites = [];
                    save([audiolocation '\Detected_Bite_Events.mat'], 'Audio_analysis');
                end
            end
        end

        % Button pushed function: MarktrialtoincludeButton
        function MarktrialtoincludeButtonPushed(app, event)
            trial = app.TrialsListBox.Value;
            if numel(trial) == 1
                app.Video_annotation(trial).Disgard = 0;
                update_annotations(app);
                app.MarktrialtodisgardButton.Enable = 1;
                app.MarktrialtoincludeButton.Enable = 0;
                app.Label.Text = num2str(str2double(app.Label.Text)-1);
                app.MarkasreadytobiteButton.Enable = 1;
                app.MarkasfeedingendButton.Enable = 1;
                app.TrialsListBox.Items{trial}(end-7:end) = [];
            end
        end

        % Button pushed function: DetectBitesButton
        function DetectBitesButtonPushed(app, event)
            DetectBites(app, app.Exp_Path, app.SampleRate, app.lowpassfreq, app.highpassfreq, app.Video_annotation, app.standard_delay);
        end

        % Button pushed function: BiteAnalysisButton
        function BiteAnalysisButtonPushed(app, event)
            clc;
            Bite_Analysis(app, app.Exp_Path, app.bite_bin);
        end

        % Button pushed function: AddfolderButton
        function AddfolderButtonPushed(app, event)
            curpwd = pwd;
            try
                cd(app.Exp_Path);
                Extra_Path = uigetdir(app.Exp_Path, 'Pick a folder');
            catch
                Extra_Path = uigetdir('', 'Pick a folder');
            end
            if Extra_Path ~= 0
                if isempty(app.ListBox.Items)
                    app.ListBox.Items{1} = Extra_Path;
                    app.ListBox.ItemsData = 1;
                    app.ListBox.Value = 1;
                else
                    app.ListBox.Items{end+1} = Extra_Path;
                    app.ListBox.ItemsData = 1:numel(app.ListBox.Items);
                    app.ListBox.Value = [app.ListBox.Value numel(app.ListBox.Items)];
                end
            end
            cd(curpwd);
            figure(app.FreemovingFeedingAnalysisUIFigure);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            value = app.ListBox.Value;
            if ~isempty(value)
                app.ListBox.Items(value) = [];
                if ~isempty(app.ListBox.Items)
                    app.ListBox.ItemsData = 1:numel(app.ListBox.Items);
                    app.ListBox.Value = [];
                else
                    app.ListBox.ItemsData = [];
                end
            end
        end

        % Button pushed function: MarkasreadytobiteButton
        function MarkasreadytobiteButtonPushed(app, event)
            trial = app.TrialsListBox.Value;
            if numel(trial) == 1
                app.Video_annotation(trial).time_ready2bite = app.Slider.Value;
                update_annotations(app);
            end
        end

        % Button pushed function: MarkasfeedingendButton
        function MarkasfeedingendButtonPushed(app, event)
            trial = app.TrialsListBox.Value;
            if numel(trial) == 1
                app.Video_annotation(trial).time_feeding_end = app.Slider.Value;
                update_annotations(app);
            end
        end

        % Button pushed function: WriteVideoButton
        function WriteVideoButtonPushed(app, event)
            timestart = app.timerangesEditField.Value;
            timestop = app.toEditField.Value;
            duration = [timestart timestop];
            value = [app.Video1CheckBox.Value app.Video2CheckBox.Value app.Video3CheckBox.Value];
            csvdata = cell(1, 3);
            readerobj = cell(1, 3);
            num_track = cell(1, 3);
            for i = 1:3
                csvdata{i} = eval(['app.csvdata' num2str(i)]);
                readerobj{i} = eval(['app.readerobj' num2str(i)]);
                num_track{i} = eval(['app.num_track' num2str(i)]);
            end
            label_table = table2array(app.UITable.Data);
            for i = 1:3
                if value(i)
                    write2video(app, app.FrameRate, csvdata{i}, readerobj{i}, num_track{i}, duration, label_table, app.label_trace, app.sqsize,...
                        app.dotsize, app.labelcolor, app.alphavalue, app.fpdata);
                end
            end
            msgbox('Done !');
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            Video1CheckBoxValueChanged(app, event);
            Video2CheckBoxValueChanged(app, event);
            Video3CheckBoxValueChanged(app, event);
        end

        % Value changed function: TrackingDataCheckBox
        function TrackingDataCheckBoxValueChanged(app, event)
            trial = app.TrialsListBox.Value;
            if numel(trial) ~= 1
                app.TrackingDataCheckBox.Value = 0;
                return;
            end
            value = app.TrackingDataCheckBox.Value;
            app.TrackingDataCheckBox.Enable = 0;
            drawnow;
            if value
                app.UITable.Enable = 'on';
                cam1track = find_tracking_filename(app.Exp_Path, ['PG1camera' num2str(trial)]);
                cam2track = find_tracking_filename(app.Exp_Path, ['PG2camera' num2str(trial)]);
                cam3track = find_tracking_filename(app.readerobj3.Path, ['PG3camera' num2str(trial)]);
                
                filename = cell(1, 3);
                filename{1} = cam1track;
                filename{2} = cam2track;
                filename{3} = cam3track;
                num_track = cell(1, 3);
                parfor i = 1:3
                    if ~isempty(filename{i})
                        [num_track{i}, ~, ~] = xlsread(filename{i});
                    end
                end
                app.num_track1 = num_track{1};
                app.num_track2 = num_track{2};
                app.num_track3 = num_track{3};
                UITableCellEdit(app, event);
            else
                app.UITable.Enable = 'off';
                app.num_track1 = [];
                app.num_track2 = [];
                app.num_track3 = [];
                UITableCellEdit(app, event);
            end
            app.TrackingDataCheckBox.Enable = 1;
        end

        % Button pushed function: VisualizeTrajectoryButton
        function VisualizeTrajectoryButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            VisualizeTrajectory(app, app.Exp_Path, FrameRate, app.nmedian, app.fpdata);
        end

        % Button pushed function: PickCPFolderButton
        function PickCPFolderButtonPushed(app, event)
            curpwd = pwd;
            try
                cd(app.Exp_Path);
                CP_Path = uigetdir(app.Exp_Path, 'Pick the Camera Parameter Folder');
            catch
                CP_Path = uigetdir('', 'Pick the Camera Parameter Folder');
            end
            if CP_Path ~= 0
                try
                    app.EditField_2.Value = CP_Path;
                catch
                    app.EditField_2.Value = '';
                end
            end
            cd(curpwd);
            figure(app.FreemovingFeedingAnalysisUIFigure);
        end

        % Button pushed function: PreprocessTrajectoriesButton
        function PreprocessTrajectoriesButtonPushed(app, event)
            try
                pixel2mm(app.Exp_Path, app.EditField_2.Value);
            end
        end

        % Button pushed function: AverageTrajectoryButton
        function AverageTrajectoryButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            AverageTrajectory(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: VisualizeDistanceButton
        function VisualizeDistanceButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            VisualizeDistance(app, app.Exp_Path, FrameRate, app.nmedian, app.pawsegment);
            
            try
                if app.Video1CheckBox.Value
                    Points2Image(app, FrameRate, app.readerobj1, app.num_track1, app.csvdata1);
                end
                if app.Video2CheckBox.Value
                    Points2Image(app, FrameRate, app.readerobj2, app.num_track2, app.csvdata2);
                end
                if app.Video3CheckBox.Value
                    Points2Image(app, FrameRate, app.readerobj3, app.num_track3, app.csvdata3);
                end
            end
        end

        % Value changed function: timerangesEditField
        function timerangesEditFieldValueChanged(app, event)
            value = app.timerangesEditField.Value;
            if value < 0 || value >= app.toEditField.Value
                app.timerangesEditField.Value = 0;
            end
        end

        % Value changed function: toEditField
        function toEditFieldValueChanged(app, event)
            value = app.toEditField.Value;
            if value <= app.timerangesEditField.Value
                app.toEditField.Value = inf;
            end
        end

        % Button pushed function: AverageDistanceButton
        function AverageDistanceButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            AverageDistance(app, app.Exp_Path, FrameRate, app.nmedian, app.zspeed_threshold);
        end

        % Button pushed function: AHOrientationButton
        function AHOrientationButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            AHOrientation(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: CloseFiguresButton
        function CloseFiguresButtonPushed(app, event)
            app.FreemovingFeedingAnalysisUIFigure.Visible = 'off';
            close all;
            app.FreemovingFeedingAnalysisUIFigure.Visible = 'on';
        end

        % Button pushed function: AverageOrientationButton
        function AverageOrientationButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            AverageOrientation(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: PhaseAnalysisButton
        function PhaseAnalysisButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            PhaseAnalysis(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: PhaseAnalysisAllButton
        function PhaseAnalysisAllButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            PhaseAnalysisAll(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Value changed function: GotoEditField
        function GotoEditFieldValueChanged(app, event)
            value = app.GotoEditField.Value;
            app.Slider.Value = value;
            SliderValueChanged(app, event);
        end

        % Button pushed function: PCAZaxisButton
        function PCAZaxisButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            PCAZaxis(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: EthogramButton
        function EthogramButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            Ethogram(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Value changed function: OptSessionCheckBox
        function OptSessionCheckBoxValueChanged(app, event)
            if ~isempty(app.TrialsListBox.Value)
                if app.Video1CheckBox.Value
                    if strcmp(app.readerobj1.Name(end-2:end), 'avi')
                        frameID = round(app.readerobj1.CurrentTime*app.readerobj1.FrameRate);
                    elseif strcmp(app.readerobj1.Name(end-2:end), 'mp4')
                        frameID = round(app.readerobj1.CurrentTime*app.readerobj1.FrameRate)-1;
                    end
                    generate_square(app, app.csvdata1, app.UIAxes, frameID, 1)
                end
                if app.Video2CheckBox.Value
                    if strcmp(app.readerobj2.Name(end-2:end), 'avi')
                        frameID = round(app.readerobj2.CurrentTime*app.readerobj2.FrameRate);
                    elseif strcmp(app.readerobj2.Name(end-2:end), 'mp4')
                        frameID = round(app.readerobj2.CurrentTime*app.readerobj2.FrameRate)-1;
                    end
                    generate_square(app, app.csvdata2, app.UIAxes_2, frameID, 2)
                end
                if app.Video3CheckBox.Value
                    if strcmp(app.readerobj3.Name(end-2:end), 'avi')
                        frameID = round(app.readerobj3.CurrentTime*app.readerobj3.FrameRate);
                    elseif strcmp(app.readerobj3.Name(end-2:end), 'mp4')
                        frameID = round(app.readerobj3.CurrentTime*app.readerobj3.FrameRate)-1;
                    end
                    generate_square(app, app.csvdata3, app.UIAxes_3, frameID, 3)
                end
            end
            generate_plot(app, app.Slider.Limits(2));
        end

        % Value changed function: FPCheckBox
        function FPCheckBoxValueChanged(app, event)
            generate_plot(app, app.Slider.Limits(2));
        end

        % Button pushed function: GCaMPtoBiteButton
        function GCaMPtoBiteButtonPushed(app, event)
            GCaMP2bite(app, app.Exp_Path, app.fpdata);
        end

        % Button pushed function: GCaMPtoBiteAllButton
        function GCaMPtoBiteAllButtonPushed(app, event)
            GCaMP2bite_all(app, app.Exp_Path);
        end

        % Button pushed function: GCaMPtoTrajectoryAllButton
        function GCaMPtoTrajectoryAllButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            GCaMP2SingleMovement(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Value changed function: OmissionCheckBox
        function OmissionCheckBoxValueChanged(app, event)
            trial = app.TrialsListBox.Value;
            value = app.OmissionCheckBox.Value;
            app.Video_annotation(trial).Omission = value;
            update_annotations(app);
        end

        % Button pushed function: AverageOmissionButton
        function AverageOmissionButtonPushed(app, event)
            AverageOmission(app, app.Exp_Path);
        end

        % Button pushed function: FoodinMouthButton
        function FoodinMouthButtonPushed(app, event)
            if ~app.CheckEventCheckBox.Value
                app.LabelledEvents.FoodinMouth = [app.LabelledEvents.FoodinMouth; app.Slider.Value];
                update_LabelledEvents(app);
            else
                checkORdelete_event(app, event, 'FoodinMouth', '');
            end
        end

        % Value changed function: PawLReachButton
        function PawLReachButtonValueChanged(app, event)
            if ~app.CheckEventCheckBox.Value
                value = app.PawLReachButton.Value;
                if value
                    app.LabelledEvents.PawLReachStart = [app.LabelledEvents.PawLReachStart; app.Slider.Value];
                else
                    app.LabelledEvents.PawLReachEnd = [app.LabelledEvents.PawLReachEnd; app.Slider.Value];
                end
                update_LabelledEvents(app);
            else
                app.PawLReachButton.Value = 0;
                checkORdelete_event(app, event, 'PawLReachStart', 'PawLReachEnd');
            end
        end

        % Value changed function: PawRReachButton
        function PawRReachButtonValueChanged(app, event)
            if ~app.CheckEventCheckBox.Value
                value = app.PawRReachButton.Value;
                if value
                    app.LabelledEvents.PawRReachStart = [app.LabelledEvents.PawRReachStart; app.Slider.Value];
                else
                    app.LabelledEvents.PawRReachEnd = [app.LabelledEvents.PawRReachEnd; app.Slider.Value];
                end
                update_LabelledEvents(app);
            else
                app.PawRReachButton.Value = 0;
                checkORdelete_event(app, event, 'PawRReachStart', 'PawRReachEnd');
            end
        end

        % Value changed function: SitButton
        function SitButtonValueChanged(app, event)
            if ~app.CheckEventCheckBox.Value
                value = app.SitButton.Value;
                if value
                    app.LabelledEvents.SitStart = [app.LabelledEvents.SitStart; app.Slider.Value];
                else
                    app.LabelledEvents.SitEnd = [app.LabelledEvents.SitEnd; app.Slider.Value];
                    if numel(app.LabelledEvents.SitEnd) == 1
                        MarkasreadytobiteButtonPushed(app, event);
                    end
                end
                update_LabelledEvents(app);
            else
                app.SitButton.Value = 0;
                checkORdelete_event(app, event, 'SitStart', 'SitEnd');
            end
        end

        % Value changed function: PawLAdjustmentButton
        function PawLAdjustmentButtonValueChanged(app, event)
            if ~app.CheckEventCheckBox.Value
                value = app.PawLAdjustmentButton.Value;
                if value
                    app.LabelledEvents.PawLAdjustmentStart = [app.LabelledEvents.PawLAdjustmentStart; app.Slider.Value];
                else
                    app.LabelledEvents.PawLAdjustmentEnd = [app.LabelledEvents.PawLAdjustmentEnd; app.Slider.Value];
                end
                update_LabelledEvents(app);
            else
                app.PawLAdjustmentButton.Value = 0;
                checkORdelete_event(app, event, 'PawLAdjustmentStart', 'PawLAdjustmentEnd');
            end
        end

        % Value changed function: PawRAdjustmentButton
        function PawRAdjustmentButtonValueChanged(app, event)
            if ~app.CheckEventCheckBox.Value
                value = app.PawRAdjustmentButton.Value;
                if value
                    app.LabelledEvents.PawRAdjustmentStart = [app.LabelledEvents.PawRAdjustmentStart; app.Slider.Value];
                else
                    app.LabelledEvents.PawRAdjustmentEnd = [app.LabelledEvents.PawRAdjustmentEnd; app.Slider.Value];
                end
                update_LabelledEvents(app);
            else
                app.PawRAdjustmentButton.Value = 0;
                checkORdelete_event(app, event, 'PawRAdjustmentStart', 'PawRAdjustmentEnd');
            end
        end

        % Value changed function: BiteBoutButton
        function BiteBoutButtonValueChanged(app, event)
            if ~app.CheckEventCheckBox.Value
                value = app.BiteBoutButton.Value;
                if value
                    app.LabelledEvents.BiteBoutStart = [app.LabelledEvents.BiteBoutStart; app.Slider.Value];
                else
                    app.LabelledEvents.BiteBoutEnd = [app.LabelledEvents.BiteBoutEnd; app.Slider.Value];
                end
                update_LabelledEvents(app);
            else
                checkORdelete_event(app, event, 'BiteBoutStart', '');
            end
            app.BiteBoutButton.Value = 0;
        end

        % Button pushed function: FeedingEndButton
        function FeedingEndButtonPushed(app, event)
            if ~app.CheckEventCheckBox.Value
                app.LabelledEvents.FeedingEnd = [app.LabelledEvents.FeedingEnd; app.Slider.Value];
                update_LabelledEvents(app);
                MarkasfeedingendButtonPushed(app, event);
            else
                checkORdelete_event(app, event, 'FeedingEnd', '');
            end
        end

        % Button pushed function: SaveFromWorkspaceButton
        function SaveFromWorkspaceButtonPushed(app, event)
            app.LabelledEvents = evalin('base', 'LabelledEvents');
            update_LabelledEvents(app);
        end

        % Key press function: FreemovingFeedingAnalysisUIFigure
        function FreemovingFeedingAnalysisUIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case {'d', 'rightarrow'}
                    if app.NextFrameButton.Enable && app.LastFrameButton.Enable
                        NextFrameButtonPushed(app, event);
                    end
                case {'a', 'leftarrow'}
                    if app.LastFrameButton.Enable && app.NextFrameButton.Enable
                        LastFrameButtonPushed(app, event);
                    end
                case {'s', 'downarrow'}
                    value = app.TrialsListBox.Value;
                    if value ~= max(app.TrialsListBox.ItemsData)
                        app.TrialsListBox.Value = value+1;
                    end
                    TrialsListBoxValueChanged(app, event);
                case {'w', 'uparrow'}
                    value = app.TrialsListBox.Value;
                    if value ~= 1
                        app.TrialsListBox.Value = value-1;
                    end
                    TrialsListBoxValueChanged(app, event);
                case 'f'
                    if app.NextFrameButton.Enable && app.LastFrameButton.Enable
                        for i = 1:6
                            NextFrameButtonPushed(app, event);
                            pause(0.05);
                        end
                    end
                case 'c'
                    app.PlayButton.Value = ~app.PlayButton.Value;
                    PlayButtonValueChanged(app, event);
            end
        end

        % Value changed function: MouthOpenButton
        function MouthOpenButtonValueChanged(app, event)
            if ~app.CheckEventCheckBox.Value
                value = app.MouthOpenButton.Value;
                if value
                    app.LabelledEvents.MouthOpen = [app.LabelledEvents.MouthOpen; app.Slider.Value];
                else
                    app.LabelledEvents.MouthClosed = [app.LabelledEvents.MouthClosed; app.Slider.Value];
                end
                update_LabelledEvents(app);
            else
                app.MouthOpenButton.Value = 0;
                checkORdelete_event(app, event, 'MouthOpen', 'MouthClosed');
            end
        end

        % Value changed function: TongueOutButton
        function TongueOutButtonValueChanged(app, event)
            if ~app.CheckEventCheckBox.Value
                value = app.TongueOutButton.Value;
                if value
                    app.LabelledEvents.TongueOut = [app.LabelledEvents.TongueOut; app.Slider.Value];
                else
                    app.LabelledEvents.TongueIn = [app.LabelledEvents.TongueIn; app.Slider.Value];
                end
                update_LabelledEvents(app);
            else
                app.TongueOutButton.Value = 0;
                checkORdelete_event(app, event, 'TongueOut', 'TongueIn');
            end
        end

        % Button pushed function: GCaMPtoEventsButton
        function GCaMPtoEventsButtonPushed(app, event)
            GCaMP2Events(app, app.Exp_Path);
        end

        % Button pushed function: ToNoseButton
        function ToNoseButtonPushed(app, event)
            if ~app.CheckEventCheckBox.Value
                if isfield(app.LabelledEvents, 'ToNose')
                    app.LabelledEvents.ToNose = [app.LabelledEvents.ToNose; app.Slider.Value];
                else
                    app.LabelledEvents.ToNose = app.Slider.Value;
                end
                update_LabelledEvents(app);
            else
                checkORdelete_event(app, event, 'ToNose', '');
            end
        end

        % Value changed function: BrightnessEditField
        function BrightnessEditFieldValueChanged(app, event)
            value = app.Video1CheckBox.Value;
            if value
                if ~isempty(app.TrialsListBox.Value)
                    if strcmp(app.readerobj1.Name(end-2:end), 'avi')
                        app.readerobj1.CurrentTime = app.currenttime-1/app.readerobj1.FrameRate;
                    elseif strcmp(app.readerobj1.Name(end-2:end), 'mp4')
                        app.readerobj1.CurrentTime = app.currenttime;
                    end
                    generate_content(app, app.readerobj1, app.num_track1, app.UIAxes, app.csvdata1, 1);
                end
            end
            value = app.Video2CheckBox.Value;
            if value
                if ~isempty(app.TrialsListBox.Value)
                    if strcmp(app.readerobj2.Name(end-2:end), 'avi')
                        app.readerobj2.CurrentTime = app.currenttime-1/app.readerobj2.FrameRate;
                    elseif strcmp(app.readerobj2.Name(end-2:end), 'mp4')
                        app.readerobj2.CurrentTime = app.currenttime;
                    end
                    generate_content(app, app.readerobj2, app.num_track2, app.UIAxes_2, app.csvdata2, 2);
                end
            end
            value = app.Video3CheckBox.Value;
            if value
                if ~isempty(app.TrialsListBox.Value)
                    if strcmp(app.readerobj3.Name(end-2:end), 'avi')
                        app.readerobj3.CurrentTime = app.currenttime-1/app.readerobj3.FrameRate;
                    elseif strcmp(app.readerobj3.Name(end-2:end), 'mp4')
                        app.readerobj3.CurrentTime = app.currenttime;
                    end
                    generate_content(app, app.readerobj3, app.num_track3, app.UIAxes_3, app.csvdata3, 3);
                end
            end
        end

        % Button pushed function: EventPlotButton
        function EventPlotButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            EventPlot(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: RetrievalStartButton
        function RetrievalStartButtonPushed(app, event)
            if ~app.CheckEventCheckBox.Value
                if isfield(app.LabelledEvents, 'RetrievalStart')
                    app.LabelledEvents.RetrievalStart = [app.LabelledEvents.RetrievalStart; app.Slider.Value];
                else
                    app.LabelledEvents.RetrievalStart = app.Slider.Value;
                end
                update_LabelledEvents(app);
            else
                checkORdelete_event(app, event, 'RetrievalStart', '');
            end
        end

        % Value changed function: MouthRetrievalButton
        function MouthRetrievalButtonValueChanged(app, event)
            if ~app.CheckEventCheckBox.Value
                value = app.MouthRetrievalButton.Value;
                if value
                    app.LabelledEvents.MouthRetrievalStart = [app.LabelledEvents.MouthRetrievalStart; app.Slider.Value];
                else
                    app.LabelledEvents.MouthRetrievalEnd = [app.LabelledEvents.MouthRetrievalEnd; app.Slider.Value];
                end
                update_LabelledEvents(app);
            else
                app.MouthRetrievalButton.Value = 0;
                checkORdelete_event(app, event, 'MouthRetrievalStart', 'MouthRetrievalEnd');
            end
        end

        % Button pushed function: TrajectorytoEventsButton
        function TrajectorytoEventsButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            TrajectorytoEvents(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: DetectDistanceButton
        function DetectDistanceButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            DetectDistance(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: FoodDetectionButton
        function FoodDetectionButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            FoodDetection(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: RemoveBitesinTimeButton
        function RemoveBitesinTimeButtonPushed(app, event)
            timestart = app.timerangesEditField.Value;
            timestop = app.toEditField.Value;
            duration = [timestart timestop];
            RemoveBitesinTimeRange(app, app.Exp_Path, duration);
        end

        % Button pushed function: AligntoBiteButton
        function AligntoBiteButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            AligntoBite(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: AligntoBiteAllButton
        function AligntoBiteAllButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            AligntoBiteAll(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: UpdateLabelsforLickButton
        function UpdateLabelsforLickButtonPushed(app, event)
            Update_Labels4LickTrials(app, app.Exp_Path);
        end

        % Button pushed function: SelectTrialsButton
        function SelectTrialsButtonPushed(app, event)
            app.TrialsListBox.Value = eval(app.EditField_3.Value);
        end

        % Button pushed function: GCaMPtolickButton
        function GCaMPtolickButtonPushed(app, event)
            GCaMP2lick(app, app.Exp_Path);
        end

        % Button pushed function: HMMforBoutStartButton
        function HMMforBoutStartButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            HMM4BoutStart(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: HMMforSitButton
        function HMMforSitButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            HMM4Sit(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: PawAdjAnalysisButton
        function PawAdjAnalysisButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            PawAdjAnalysis(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: EventPropertyButton
        function EventPropertyButtonPushed(app, event)
            EventProperty(app, app.Exp_Path);
        end

        % Button pushed function: AligntoAdjAllButton
        function AligntoAdjAllButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            AligntoAdjAll(app, app.Exp_Path, FrameRate, app.nmedian);
        end

        % Button pushed function: GCaMPtoAllButton
        function GCaMPtoAllButtonPushed(app, event)
            GCaMP2All(app, app.Exp_Path);
        end

        % Button pushed function: GCaMPTrajCorrButton
        function GCaMPTrajCorrButtonPushed(app, event)
            FrameRate = mean([app.readerobj1.FrameRate app.readerobj2.FrameRate app.readerobj3.FrameRate]);
            GCaMPTrajCorr(app, app.Exp_Path, FrameRate, app.nmedian);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FreemovingFeedingAnalysisUIFigure = uifigure('Visible', 'off');
            app.FreemovingFeedingAnalysisUIFigure.Name = 'Free-moving Feeding Analysis';
            app.FreemovingFeedingAnalysisUIFigure.KeyPressFcn = createCallbackFcn(app, @FreemovingFeedingAnalysisUIFigureKeyPress, true);
            app.ExpFolderButton = uibutton(app.FreemovingFeedingAnalysisUIFigure, 'push');
            app.ExpFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ExpFolderButtonPushed, true);
            app.ExpFolderButton.Text = 'Exp Folder';
            app.EditField = uieditfield(app.FreemovingFeedingAnalysisUIFigure, 'text');
            app.EditField.Editable = 'off';
            app.TrialsListBoxLabel = uilabel(app.FreemovingFeedingAnalysisUIFigure);
            app.TrialsListBoxLabel.Text = 'Trials';
            app.TrialsListBox = uilistbox(app.FreemovingFeedingAnalysisUIFigure);
            app.TrialsListBox.Items = {};
            app.TrialsListBox.Multiselect = 'on';
            app.TrialsListBox.ValueChangedFcn = createCallbackFcn(app, @TrialsListBoxValueChanged, true);
            app.TrialsListBox.Value = {};
            app.VideoPanel = uipanel(app.FreemovingFeedingAnalysisUIFigure);
            app.VideoPanel.Title = 'Video';
            app.UIAxes = uiaxes(app.VideoPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.VideoPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.VideoPanel);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.Slider = uislider(app.VideoPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Video1CheckBox = uicheckbox(app.VideoPanel);
            app.Video1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Video1CheckBoxValueChanged, true);
            app.Video1CheckBox.Text = 'Video1';
            app.Video2CheckBox = uicheckbox(app.VideoPanel);
            app.Video2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Video2CheckBoxValueChanged, true);
            app.Video2CheckBox.Text = 'Video2';
            app.Video2CheckBox.Value = true;
            app.Video3CheckBox = uicheckbox(app.VideoPanel);
            app.Video3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Video3CheckBoxValueChanged, true);
            app.Video3CheckBox.Text = 'Video3';
            app.VideoforMarkingButtonGroup = uibuttongroup(app.VideoPanel);
            app.VideoforMarkingButtonGroup.Title = 'Video for Marking';
            app.Video1Button = uiradiobutton(app.VideoforMarkingButtonGroup);
            app.Video1Button.Text = 'Video1';
            app.Video2Button = uiradiobutton(app.VideoforMarkingButtonGroup);
            app.Video2Button.Text = 'Video2';
            app.Video2Button.Value = true;
            app.Video3Button = uiradiobutton(app.VideoforMarkingButtonGroup);
            app.Video3Button.Text = 'Video3';
            app.MarkasreadytobiteButton = uibutton(app.VideoPanel, 'push');
            app.MarkasreadytobiteButton.ButtonPushedFcn = createCallbackFcn(app, @MarkasreadytobiteButtonPushed, true);
            app.MarkasreadytobiteButton.Text = 'Mark as ready to bite';
            app.MarkasfeedingendButton = uibutton(app.VideoPanel, 'push');
            app.MarkasfeedingendButton.ButtonPushedFcn = createCallbackFcn(app, @MarkasfeedingendButtonPushed, true);
            app.MarkasfeedingendButton.Text = 'Mark as feeding end';
            app.FrameRateEditFieldLabel = uilabel(app.VideoPanel);
            app.FrameRateEditFieldLabel.Text = 'FrameRate';
            app.FrameRateEditField = uieditfield(app.VideoPanel, 'numeric');
            app.FrameRateEditField.Limits = [0 Inf];
            app.FrameRateEditField.ValueChangedFcn = createCallbackFcn(app, @FrameRateEditFieldValueChanged, true);
            app.FrameRateEditField.Value = 20;
            app.MarktrialtodisgardButton = uibutton(app.VideoPanel, 'push');
            app.MarktrialtodisgardButton.ButtonPushedFcn = createCallbackFcn(app, @MarktrialtodisgardButtonPushed, true);
            app.MarktrialtodisgardButton.Enable = 'off';
            app.MarktrialtodisgardButton.Text = 'Mark trial to disgard';
            app.MarktrialtoincludeButton = uibutton(app.VideoPanel, 'push');
            app.MarktrialtoincludeButton.ButtonPushedFcn = createCallbackFcn(app, @MarktrialtoincludeButtonPushed, true);
            app.MarktrialtoincludeButton.Enable = 'off';
            app.MarktrialtoincludeButton.Text = 'Mark trial to include';
            app.Label = uilabel(app.VideoPanel);
            app.Label.Text = '0';
            app.BrightnessEditFieldLabel = uilabel(app.VideoPanel);
            app.BrightnessEditFieldLabel.Text = 'Brightness';
            app.BrightnessEditField = uieditfield(app.VideoPanel, 'numeric');
            app.BrightnessEditField.Limits = [0 1];
            app.BrightnessEditField.ValueChangedFcn = createCallbackFcn(app, @BrightnessEditFieldValueChanged, true);
            app.BrightnessEditField.Value = 1;
            app.UITable = uitable(app.VideoPanel);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Enable = 'off';
            app.TrackingDataCheckBox = uicheckbox(app.VideoPanel);
            app.TrackingDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackingDataCheckBoxValueChanged, true);
            app.TrackingDataCheckBox.Text = 'Tracking Data';
            app.DropDown = uidropdown(app.VideoPanel);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            app.VisualizeTrajectoryButton = uibutton(app.VideoPanel, 'push');
            app.VisualizeTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeTrajectoryButtonPushed, true);
            app.VisualizeTrajectoryButton.Text = 'Visualize Trajectory';
            app.PickCPFolderButton = uibutton(app.VideoPanel, 'push');
            app.PickCPFolderButton.ButtonPushedFcn = createCallbackFcn(app, @PickCPFolderButtonPushed, true);
            app.PickCPFolderButton.Text = 'Pick CP Folder';
            app.EditField_2 = uieditfield(app.VideoPanel, 'text');
            app.EditField_2.Editable = 'off';
            app.PreprocessTrajectoriesButton = uibutton(app.VideoPanel, 'push');
            app.PreprocessTrajectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessTrajectoriesButtonPushed, true);
            app.PreprocessTrajectoriesButton.Text = 'Preprocess Trajectories';
            app.AverageTrajectoryButton = uibutton(app.VideoPanel, 'push');
            app.AverageTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @AverageTrajectoryButtonPushed, true);
            app.AverageTrajectoryButton.Text = 'Average Trajectory';
            app.timerangesEditFieldLabel = uilabel(app.VideoPanel);
            app.timerangesEditFieldLabel.Text = 'time range (s)';
            app.timerangesEditField = uieditfield(app.VideoPanel, 'numeric');
            app.timerangesEditField.ValueChangedFcn = createCallbackFcn(app, @timerangesEditFieldValueChanged, true);
            app.timerangesEditField.Value = 0.2;
            app.toEditFieldLabel = uilabel(app.VideoPanel);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.VideoPanel, 'numeric');
            app.toEditField.ValueChangedFcn = createCallbackFcn(app, @toEditFieldValueChanged, true);
            app.toEditField.Value = 20;
            app.DropDown_2 = uidropdown(app.VideoPanel);
            app.DropDown_2.Items = {};
            app.DropDown_2.Value = {};
            app.toLabel = uilabel(app.VideoPanel);
            app.toLabel.Text = 'to';
            app.DropDown_3 = uidropdown(app.VideoPanel);
            app.DropDown_3.Items = {};
            app.DropDown_3.Value = {};
            app.VisualizeDistanceButton = uibutton(app.VideoPanel, 'push');
            app.VisualizeDistanceButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeDistanceButtonPushed, true);
            app.VisualizeDistanceButton.Text = 'Visualize Distance';
            app.AverageDistanceButton = uibutton(app.VideoPanel, 'push');
            app.AverageDistanceButton.ButtonPushedFcn = createCallbackFcn(app, @AverageDistanceButtonPushed, true);
            app.AverageDistanceButton.Text = 'Average Distance';
            app.AHOrientationButton = uibutton(app.VideoPanel, 'push');
            app.AHOrientationButton.ButtonPushedFcn = createCallbackFcn(app, @AHOrientationButtonPushed, true);
            app.AHOrientationButton.Text = 'AH Orientation';
            app.AverageOrientationButton = uibutton(app.VideoPanel, 'push');
            app.AverageOrientationButton.ButtonPushedFcn = createCallbackFcn(app, @AverageOrientationButtonPushed, true);
            app.AverageOrientationButton.Text = 'Average Orientation';
            app.PhaseAnalysisButton = uibutton(app.VideoPanel, 'push');
            app.PhaseAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseAnalysisButtonPushed, true);
            app.PhaseAnalysisButton.Text = 'Phase Analysis';
            app.PhaseAnalysisAllButton = uibutton(app.VideoPanel, 'push');
            app.PhaseAnalysisAllButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseAnalysisAllButtonPushed, true);
            app.PhaseAnalysisAllButton.Text = 'Phase Analysis All';
            app.GotoEditFieldLabel = uilabel(app.VideoPanel);
            app.GotoEditFieldLabel.Text = 'Go to';
            app.GotoEditField = uieditfield(app.VideoPanel, 'numeric');
            app.GotoEditField.ValueChangedFcn = createCallbackFcn(app, @GotoEditFieldValueChanged, true);
            app.sLabel = uilabel(app.VideoPanel);
            app.sLabel.Text = 's';
            app.EthogramButton = uibutton(app.VideoPanel, 'push');
            app.EthogramButton.ButtonPushedFcn = createCallbackFcn(app, @EthogramButtonPushed, true);
            app.EthogramButton.Text = 'Ethogram';
            app.LabelEventsPanel = uipanel(app.VideoPanel);
            app.LabelEventsPanel.Title = 'Label Events';
            app.FoodinMouthButton = uibutton(app.LabelEventsPanel, 'push');
            app.FoodinMouthButton.ButtonPushedFcn = createCallbackFcn(app, @FoodinMouthButtonPushed, true);
            app.FoodinMouthButton.Text = 'Food in Mouth';
            app.PawLReachButton = uibutton(app.LabelEventsPanel, 'state');
            app.PawLReachButton.ValueChangedFcn = createCallbackFcn(app, @PawLReachButtonValueChanged, true);
            app.PawLReachButton.Text = 'PawL Reach';
            app.PawRReachButton = uibutton(app.LabelEventsPanel, 'state');
            app.PawRReachButton.ValueChangedFcn = createCallbackFcn(app, @PawRReachButtonValueChanged, true);
            app.PawRReachButton.Text = 'PawR Reach';
            app.SitButton = uibutton(app.LabelEventsPanel, 'state');
            app.SitButton.ValueChangedFcn = createCallbackFcn(app, @SitButtonValueChanged, true);
            app.SitButton.Text = 'Sit';
            app.PawLAdjustmentButton = uibutton(app.LabelEventsPanel, 'state');
            app.PawLAdjustmentButton.ValueChangedFcn = createCallbackFcn(app, @PawLAdjustmentButtonValueChanged, true);
            app.PawLAdjustmentButton.Text = 'PawL Adjustment';
            app.PawRAdjustmentButton = uibutton(app.LabelEventsPanel, 'state');
            app.PawRAdjustmentButton.ValueChangedFcn = createCallbackFcn(app, @PawRAdjustmentButtonValueChanged, true);
            app.PawRAdjustmentButton.Text = 'PawR Adjustment';
            app.BiteBoutButton = uibutton(app.LabelEventsPanel, 'state');
            app.BiteBoutButton.ValueChangedFcn = createCallbackFcn(app, @BiteBoutButtonValueChanged, true);
            app.BiteBoutButton.Text = 'Bite Bout';
            app.FeedingEndButton = uibutton(app.LabelEventsPanel, 'push');
            app.FeedingEndButton.ButtonPushedFcn = createCallbackFcn(app, @FeedingEndButtonPushed, true);
            app.FeedingEndButton.Text = 'Feeding End';
            app.SaveFromWorkspaceButton = uibutton(app.LabelEventsPanel, 'push');
            app.SaveFromWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFromWorkspaceButtonPushed, true);
            app.SaveFromWorkspaceButton.Text = 'Save From Workspace';
            app.MouthOpenButton = uibutton(app.LabelEventsPanel, 'state');
            app.MouthOpenButton.ValueChangedFcn = createCallbackFcn(app, @MouthOpenButtonValueChanged, true);
            app.MouthOpenButton.Text = 'Mouth Open';
            app.TongueOutButton = uibutton(app.LabelEventsPanel, 'state');
            app.TongueOutButton.ValueChangedFcn = createCallbackFcn(app, @TongueOutButtonValueChanged, true);
            app.TongueOutButton.Text = 'Tongue Out';
            app.ToNoseButton = uibutton(app.LabelEventsPanel, 'push');
            app.ToNoseButton.ButtonPushedFcn = createCallbackFcn(app, @ToNoseButtonPushed, true);
            app.ToNoseButton.Text = 'To Nose';
            app.EventPlotButton = uibutton(app.LabelEventsPanel, 'push');
            app.EventPlotButton.ButtonPushedFcn = createCallbackFcn(app, @EventPlotButtonPushed, true);
            app.EventPlotButton.Text = 'Event Plot';
            app.CheckEventCheckBox = uicheckbox(app.LabelEventsPanel);
            app.CheckEventCheckBox.Text = 'Check Event';
            app.Spinner = uispinner(app.LabelEventsPanel);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.Value = 1;
            app.DeleteEventCheckBox = uicheckbox(app.LabelEventsPanel);
            app.DeleteEventCheckBox.Text = 'Delete Event';
            app.RetrievalStartButton = uibutton(app.LabelEventsPanel, 'push');
            app.RetrievalStartButton.ButtonPushedFcn = createCallbackFcn(app, @RetrievalStartButtonPushed, true);
            app.RetrievalStartButton.Text = 'Retrieval Start';
            app.MouthRetrievalButton = uibutton(app.LabelEventsPanel, 'state');
            app.MouthRetrievalButton.ValueChangedFcn = createCallbackFcn(app, @MouthRetrievalButtonValueChanged, true);
            app.MouthRetrievalButton.Text = 'Mouth Retrieval';
            app.HMMforBoutStartButton = uibutton(app.LabelEventsPanel, 'push');
            app.HMMforBoutStartButton.ButtonPushedFcn = createCallbackFcn(app, @HMMforBoutStartButtonPushed, true);
            app.HMMforBoutStartButton.Text = 'HMM for Bout Start';
            app.HMMforSitButton = uibutton(app.LabelEventsPanel, 'push');
            app.HMMforSitButton.ButtonPushedFcn = createCallbackFcn(app, @HMMforSitButtonPushed, true);
            app.HMMforSitButton.Text = 'HMM for Sit';
            app.PawAdjAnalysisButton = uibutton(app.LabelEventsPanel, 'push');
            app.PawAdjAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PawAdjAnalysisButtonPushed, true);
            app.PawAdjAnalysisButton.Text = 'Paw Adj Analysis';
            app.EvaluateCheckBox = uicheckbox(app.LabelEventsPanel);
            app.EvaluateCheckBox.Text = 'Evaluate';
            app.EventPropertyButton = uibutton(app.LabelEventsPanel, 'push');
            app.EventPropertyButton.ButtonPushedFcn = createCallbackFcn(app, @EventPropertyButtonPushed, true);
            app.EventPropertyButton.Text = 'Event Property';
            app.TrajectorytoEventsButton = uibutton(app.VideoPanel, 'push');
            app.TrajectorytoEventsButton.ButtonPushedFcn = createCallbackFcn(app, @TrajectorytoEventsButtonPushed, true);
            app.TrajectorytoEventsButton.Text = 'Trajectory to Events';
            app.DetectDistanceButton = uibutton(app.VideoPanel, 'push');
            app.DetectDistanceButton.ButtonPushedFcn = createCallbackFcn(app, @DetectDistanceButtonPushed, true);
            app.DetectDistanceButton.Text = 'Detect Distance';
            app.distmmEditFieldLabel = uilabel(app.VideoPanel);
            app.distmmEditFieldLabel.Text = 'dist (mm)';
            app.thrEditField = uieditfield(app.VideoPanel, 'numeric');
            app.thrEditField.Limits = [0 Inf];
            app.FoodDetectionButton = uibutton(app.VideoPanel, 'push');
            app.FoodDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @FoodDetectionButtonPushed, true);
            app.FoodDetectionButton.Text = 'Food Detection';
            app.AligntoBiteButton = uibutton(app.VideoPanel, 'push');
            app.AligntoBiteButton.ButtonPushedFcn = createCallbackFcn(app, @AligntoBiteButtonPushed, true);
            app.AligntoBiteButton.Text = 'Align to Bite';
            app.AligntoBiteAllButton = uibutton(app.VideoPanel, 'push');
            app.AligntoBiteAllButton.ButtonPushedFcn = createCallbackFcn(app, @AligntoBiteAllButtonPushed, true);
            app.AligntoBiteAllButton.Text = 'Align to Bite All';
            app.bitesEditFieldLabel = uilabel(app.VideoPanel);
            app.bitesEditFieldLabel.Text = 'bite (s)';
            app.bitesEditField = uieditfield(app.VideoPanel, 'numeric');
            app.bitesEditField.Limits = [0 Inf];
            app.bitesEditField.Value = 0.5;
            app.AligntoAdjAllButton = uibutton(app.VideoPanel, 'push');
            app.AligntoAdjAllButton.ButtonPushedFcn = createCallbackFcn(app, @AligntoAdjAllButtonPushed, true);
            app.AligntoAdjAllButton.Text = 'Align to Adj All';
            app.AudioFiberPhotometryPanel = uipanel(app.FreemovingFeedingAnalysisUIFigure);
            app.AudioFiberPhotometryPanel.Title = 'Audio & Fiber Photometry';
            app.AudioCheckBox = uicheckbox(app.AudioFiberPhotometryPanel);
            app.AudioCheckBox.ValueChangedFcn = createCallbackFcn(app, @AudioCheckBoxValueChanged, true);
            app.AudioCheckBox.Text = 'Audio';
            app.AudioCheckBox.Value = true;
            app.UIAxes2 = uiaxes(app.AudioFiberPhotometryPanel);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.SoundCheckBox = uicheckbox(app.AudioFiberPhotometryPanel);
            app.SoundCheckBox.Text = 'Sound';
            app.PlotAudioDataButton = uibutton(app.AudioFiberPhotometryPanel, 'push');
            app.PlotAudioDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAudioDataButtonPushed, true);
            app.PlotAudioDataButton.Text = 'Plot Audio Data';
            app.DetectBitesButton = uibutton(app.AudioFiberPhotometryPanel, 'push');
            app.DetectBitesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectBitesButtonPushed, true);
            app.DetectBitesButton.Text = 'Detect Bites';
            app.ThresholdEditFieldLabel = uilabel(app.AudioFiberPhotometryPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.AudioFiberPhotometryPanel, 'numeric');
            app.ThresholdEditField.Value = 3;
            app.stdLabel = uilabel(app.AudioFiberPhotometryPanel);
            app.stdLabel.Text = 'std';
            app.CheckDetecetionCheckBox = uicheckbox(app.AudioFiberPhotometryPanel);
            app.CheckDetecetionCheckBox.Text = 'Check Detecetion';
            app.CheckDetecetionCheckBox.Value = true;
            app.BiteAnalysisButton = uibutton(app.AudioFiberPhotometryPanel, 'push');
            app.BiteAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @BiteAnalysisButtonPushed, true);
            app.BiteAnalysisButton.Text = 'Bite Analysis';
            app.SessionTypeButtonGroup = uibuttongroup(app.AudioFiberPhotometryPanel);
            app.SessionTypeButtonGroup.Title = 'Session Type';
            app.sDelay4sOnButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.sDelay4sOnButton.Text = '4s Delay, 4s On';
            app.sDelay4sOnButton.Value = true;
            app.sDelay4sOnButton_2 = uiradiobutton(app.SessionTypeButtonGroup);
            app.sDelay4sOnButton_2.Text = '0s Delay, 4s On';
            app.NoLightButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.NoLightButton.Text = 'No Light';
            app.sOnTwiceButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.sOnTwiceButton.Text = '4s On Twice';
            app.WholeTrialOnButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.WholeTrialOnButton.Text = 'Whole Trial On';
            app.BiteDeviceButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.BiteDeviceButton.Text = 'Bite Device';
            app.CombineSessionsPanel = uipanel(app.AudioFiberPhotometryPanel);
            app.CombineSessionsPanel.Title = 'Combine Sessions';
            app.ListBox = uilistbox(app.CombineSessionsPanel);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.AddfolderButton = uibutton(app.CombineSessionsPanel, 'push');
            app.AddfolderButton.ButtonPushedFcn = createCallbackFcn(app, @AddfolderButtonPushed, true);
            app.AddfolderButton.Text = 'Add folder';
            app.RemoveButton = uibutton(app.CombineSessionsPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.PCAPanel = uipanel(app.AudioFiberPhotometryPanel);
            app.PCAPanel.Title = 'PCA';
            app.PCAZaxisButton = uibutton(app.PCAPanel, 'push');
            app.PCAZaxisButton.ButtonPushedFcn = createCallbackFcn(app, @PCAZaxisButtonPushed, true);
            app.PCAZaxisButton.Text = 'PCA Z axis';
            app.PCEditFieldLabel = uilabel(app.PCAPanel);
            app.PCEditFieldLabel.Text = 'PC';
            app.PCEditField = uieditfield(app.PCAPanel, 'numeric');
            app.PCEditField.Limits = [1 Inf];
            app.PCEditField.Value = 3;
            app.FPCheckBox = uicheckbox(app.AudioFiberPhotometryPanel);
            app.FPCheckBox.ValueChangedFcn = createCallbackFcn(app, @FPCheckBoxValueChanged, true);
            app.FPCheckBox.Text = 'FP';
            app.FPCheckBox.Value = true;
            app.RemoveBitesinTimeButton = uibutton(app.AudioFiberPhotometryPanel, 'push');
            app.RemoveBitesinTimeButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBitesinTimeButtonPushed, true);
            app.RemoveBitesinTimeButton.Text = 'Remove Bites in Time';
            app.FiberPhotometryRecordingPanel = uipanel(app.AudioFiberPhotometryPanel);
            app.FiberPhotometryRecordingPanel.Title = 'Fiber Photometry Recording';
            app.UpdateLabelsforLickButton = uibutton(app.FiberPhotometryRecordingPanel, 'push');
            app.UpdateLabelsforLickButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateLabelsforLickButtonPushed, true);
            app.UpdateLabelsforLickButton.Text = 'Update Labels for Lick';
            app.EditField_3 = uieditfield(app.FiberPhotometryRecordingPanel, 'text');
            app.SelectTrialsButton = uibutton(app.FiberPhotometryRecordingPanel, 'push');
            app.SelectTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTrialsButtonPushed, true);
            app.SelectTrialsButton.Text = 'Select Trials';
            app.GCaMPtolickButton = uibutton(app.FiberPhotometryRecordingPanel, 'push');
            app.GCaMPtolickButton.ButtonPushedFcn = createCallbackFcn(app, @GCaMPtolickButtonPushed, true);
            app.GCaMPtolickButton.Text = 'GCaMP to lick';
            app.GCaMPtoBiteButton = uibutton(app.FiberPhotometryRecordingPanel, 'push');
            app.GCaMPtoBiteButton.ButtonPushedFcn = createCallbackFcn(app, @GCaMPtoBiteButtonPushed, true);
            app.GCaMPtoBiteButton.Text = 'GCaMP to Bite';
            app.GCaMPtoBiteAllButton = uibutton(app.FiberPhotometryRecordingPanel, 'push');
            app.GCaMPtoBiteAllButton.ButtonPushedFcn = createCallbackFcn(app, @GCaMPtoBiteAllButtonPushed, true);
            app.GCaMPtoBiteAllButton.Text = 'GCaMP to Bite All';
            app.OmissionCheckBox = uicheckbox(app.FiberPhotometryRecordingPanel);
            app.OmissionCheckBox.ValueChangedFcn = createCallbackFcn(app, @OmissionCheckBoxValueChanged, true);
            app.OmissionCheckBox.Text = 'Omission';
            app.AverageOmissionButton = uibutton(app.FiberPhotometryRecordingPanel, 'push');
            app.AverageOmissionButton.ButtonPushedFcn = createCallbackFcn(app, @AverageOmissionButtonPushed, true);
            app.AverageOmissionButton.Text = 'Average Omission';
            app.GCaMPtoEventsButton = uibutton(app.FiberPhotometryRecordingPanel, 'push');
            app.GCaMPtoEventsButton.ButtonPushedFcn = createCallbackFcn(app, @GCaMPtoEventsButtonPushed, true);
            app.GCaMPtoEventsButton.Text = 'GCaMP to Events';
            app.GCaMPtoTrajectoryAllButton = uibutton(app.FiberPhotometryRecordingPanel, 'push');
            app.GCaMPtoTrajectoryAllButton.ButtonPushedFcn = createCallbackFcn(app, @GCaMPtoTrajectoryAllButtonPushed, true);
            app.GCaMPtoTrajectoryAllButton.Text = 'GCaMP to Trajectory All';
            app.presEditFieldLabel = uilabel(app.FiberPhotometryRecordingPanel);
            app.presEditFieldLabel.Text = 'pre (s)';
            app.presEditField = uieditfield(app.FiberPhotometryRecordingPanel, 'numeric');
            app.presEditField.Limits = [0 Inf];
            app.presEditField.Value = 2;
            app.postsEditFieldLabel = uilabel(app.FiberPhotometryRecordingPanel);
            app.postsEditFieldLabel.Text = 'post (s)';
            app.postsEditField = uieditfield(app.FiberPhotometryRecordingPanel, 'numeric');
            app.postsEditField.Limits = [0 Inf];
            app.postsEditField.Value = 10;
            app.GCaMPtoAllButton = uibutton(app.FiberPhotometryRecordingPanel, 'push');
            app.GCaMPtoAllButton.ButtonPushedFcn = createCallbackFcn(app, @GCaMPtoAllButtonPushed, true);
            app.GCaMPtoAllButton.Text = 'GCaMP to All';
            app.GCaMPTrajCorrButton = uibutton(app.FiberPhotometryRecordingPanel, 'push');
            app.GCaMPTrajCorrButton.ButtonPushedFcn = createCallbackFcn(app, @GCaMPTrajCorrButtonPushed, true);
            app.GCaMPTrajCorrButton.Text = 'GCaMP-Traj Corr';
            app.PlayButton = uibutton(app.FreemovingFeedingAnalysisUIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.Lamp = uilamp(app.FreemovingFeedingAnalysisUIFigure);
            app.PlayBackButton = uibutton(app.FreemovingFeedingAnalysisUIFigure, 'state');
            app.PlayBackButton.ValueChangedFcn = createCallbackFcn(app, @PlayBackButtonValueChanged, true);
            app.PlayBackButton.Text = 'Play Back';
            app.FirstFrameButton = uibutton(app.FreemovingFeedingAnalysisUIFigure, 'push');
            app.FirstFrameButton.ButtonPushedFcn = createCallbackFcn(app, @FirstFrameButtonPushed, true);
            app.FirstFrameButton.Text = 'First Frame';
            app.NextFrameButton = uibutton(app.FreemovingFeedingAnalysisUIFigure, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next Frame';
            app.LastFrameButton = uibutton(app.FreemovingFeedingAnalysisUIFigure, 'push');
            app.LastFrameButton.ButtonPushedFcn = createCallbackFcn(app, @LastFrameButtonPushed, true);
            app.LastFrameButton.Text = 'Last Frame';
            app.WriteSoundTrackButton = uibutton(app.FreemovingFeedingAnalysisUIFigure, 'push');
            app.WriteSoundTrackButton.ButtonPushedFcn = createCallbackFcn(app, @WriteSoundTrackButtonPushed, true);
            app.WriteSoundTrackButton.Text = 'Write Sound Track';
            app.OptSessionCheckBox = uicheckbox(app.FreemovingFeedingAnalysisUIFigure);
            app.OptSessionCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptSessionCheckBoxValueChanged, true);
            app.OptSessionCheckBox.Text = 'Opt Session';
            app.OptSessionCheckBox.Value = true;
            app.WriteVideoButton = uibutton(app.FreemovingFeedingAnalysisUIFigure, 'push');
            app.WriteVideoButton.ButtonPushedFcn = createCallbackFcn(app, @WriteVideoButtonPushed, true);
            app.WriteVideoButton.Text = 'Write Video';
            app.CloseFiguresButton = uibutton(app.FreemovingFeedingAnalysisUIFigure, 'push');
            app.CloseFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFiguresButtonPushed, true);
            app.CloseFiguresButton.Text = 'Close Figures';
            % Show the figure after all components are created
            app.FreemovingFeedingAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Free_Moving_Feeding_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FreemovingFeedingAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FreemovingFeedingAnalysisUIFigure)
        end
    end
end